classdef MPSS_GUI4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        GeneralInputTab                 matlab.ui.container.Tab
        NumberofCPUcoresusedforthesimulationLabel  matlab.ui.control.Label
        Panel_42                        matlab.ui.container.Panel
        CPUcoresEditField               matlab.ui.control.NumericEditField
        CPUcoresEditFieldLabel          matlab.ui.control.Label
        GaussianparametersguessLabel    matlab.ui.control.Label
        Panel_43                        matlab.ui.container.Panel
        sigmaEditField                  matlab.ui.control.NumericEditField
        sigmaEditFieldLabel             matlab.ui.control.Label
        centreEditField                 matlab.ui.control.NumericEditField
        centreEditFieldLabel            matlab.ui.control.Label
        amplitudeEditField              matlab.ui.control.NumericEditField
        amplitudeEditFieldLabel         matlab.ui.control.Label
        Panel_39                        matlab.ui.container.Panel
        TypeofelementDropDown           matlab.ui.control.DropDown
        TypeofelementDropDownLabel      matlab.ui.control.Label
        ResinElementLabel               matlab.ui.control.Label
        CurvepanelLabel                 matlab.ui.control.Label
        Panel_38                        matlab.ui.container.Panel
        CurveDropDown                   matlab.ui.control.DropDown
        CurveDropDownLabel              matlab.ui.control.Label
        ParabolacurveEditField          matlab.ui.control.NumericEditField
        ParabolacurveEditFieldLabel     matlab.ui.control.Label
        OverlaminaAdditionontopofrepairLabel  matlab.ui.control.Label
        Panel_37                        matlab.ui.container.Panel
        OverlappingoftheoverlaminaDropDown  matlab.ui.control.DropDown
        OverlappingoftheoverlaminaDropDownLabel  matlab.ui.control.Label
        OverlaminaDropDown              matlab.ui.control.DropDown
        OverlaminaDropDownLabel         matlab.ui.control.Label
        WidthofdefectLabel              matlab.ui.control.Label
        Panel_36                        matlab.ui.container.Panel
        WidthofthedefectmmEditField     matlab.ui.control.NumericEditField
        WidthofthedefectmmEditFieldLabel  matlab.ui.control.Label
        PlybyplymatchLabel              matlab.ui.control.Label
        Panel_35                        matlab.ui.container.Panel
        PlymatchDropDown                matlab.ui.control.DropDown
        PlymatchDropDownLabel           matlab.ui.control.Label
        Panel_16                        matlab.ui.container.Panel
        SheetEditField                  matlab.ui.control.NumericEditField
        SheetEditFieldLabel             matlab.ui.control.Label
        FilenameEditField_2             matlab.ui.control.EditField
        FilenameEditField_2Label        matlab.ui.control.Label
        InputFilenameLabel              matlab.ui.control.Label
        Panel_4                         matlab.ui.container.Panel
        FilenameEditField               matlab.ui.control.EditField
        FilenameEditFieldLabel          matlab.ui.control.Label
        OutputFilenameLabel             matlab.ui.control.Label
        LoadingLabel                    matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        U1DisplacementinXmmEditField_2  matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        UR3rotationaldisplacementradEditField_2  matlab.ui.control.NumericEditField
        UR3rotationaldisplacementradEditField_2Label  matlab.ui.control.Label
        ValueLabel                      matlab.ui.control.Label
        LoadingDropDown                 matlab.ui.control.DropDown
        LoadingDropDownLabel            matlab.ui.control.Label
        Input2                          matlab.ui.container.Tab
        Panel_44                        matlab.ui.container.Panel
        ParentmaterialDropDown          matlab.ui.control.DropDown
        ParentmaterialDropDownLabel     matlab.ui.control.Label
        FromtoptobottomLabel            matlab.ui.control.Label
        FlagDropDown                    matlab.ui.control.DropDown
        FlagDropDownLabel               matlab.ui.control.Label
        LayupwithoutdefectegudbiaxudEditField  matlab.ui.control.EditField
        LayupwithoutdefectegudbiaxudEditFieldLabel  matlab.ui.control.Label
        LayupwithdefectegudbiaxudEditField  matlab.ui.control.EditField
        LayupwithdefectegudbiaxudEditFieldLabel  matlab.ui.control.Label
        LayupSelectionProcessLabel      matlab.ui.control.Label
        OverlappinglayerlengthLabel     matlab.ui.control.Label
        Panel_41                        matlab.ui.container.Panel
        UDoverlapmmEditField            matlab.ui.control.NumericEditField
        UDoverlapmmEditFieldLabel       matlab.ui.control.Label
        OverlaminaoverlapmmEditField    matlab.ui.control.NumericEditField
        OverlaminaoverlapmmEditFieldLabel  matlab.ui.control.Label
        BIAXoverlapmmEditField          matlab.ui.control.NumericEditField
        BIAXoverlapmmEditFieldLabel     matlab.ui.control.Label
        DefinematerialthicknessLabel    matlab.ui.control.Label
        Panel_40                        matlab.ui.container.Panel
        ThicknessoftheoverlaminabiaxorudEditField  matlab.ui.control.EditField
        ThicknessoftheoverlaminabiaxorudEditFieldLabel  matlab.ui.control.Label
        ThicknessofresininbetweenrepairandparentlaminatemmEditField  matlab.ui.control.NumericEditField
        ThicknessofresininbetweenrepairandparentlaminateEditFieldLabel  matlab.ui.control.Label
        ThicknessofBIAXfibrelaminammEditField  matlab.ui.control.NumericEditField
        ThicknessofBIAXfibrelaminammEditFieldLabel  matlab.ui.control.Label
        ThicknessofresininterfaceinbetweenlayersmmEditField  matlab.ui.control.NumericEditField
        ThicknessofresininterfaceinbetweenlayersmmEditFieldLabel  matlab.ui.control.Label
        ThicknessofUDfibrelaminammEditField  matlab.ui.control.NumericEditField
        ThicknessofUDfibrelaminammEditFieldLabel  matlab.ui.control.Label
        ElementnumberLabel              matlab.ui.control.Label
        Panel_12                        matlab.ui.container.Panel
        WidthvectorEditField            matlab.ui.control.EditField
        WidthvectorEditFieldLabel       matlab.ui.control.Label
        NoteTextArea                    matlab.ui.control.TextArea
        Geometryof2DmodelLabel_2        matlab.ui.control.Label
        Panel_8                         matlab.ui.container.Panel
        AdditionalallowanceattheedgesmmEditField  matlab.ui.control.NumericEditField
        AdditionalallowanceattheedgesmmEditFieldLabel  matlab.ui.control.Label
        WidthfrommiddleofdefectmmEditField  matlab.ui.control.NumericEditField
        WidthfrommiddleofdefectmmEditFieldLabel  matlab.ui.control.Label
        DiscretisingdensityLabel        matlab.ui.control.Label
        Panel_7                         matlab.ui.container.Panel
        DiscretisingdensityEditField    matlab.ui.control.NumericEditField
        DiscretisingdensityEditFieldLabel  matlab.ui.control.Label
        MaterialpropertiesTab           matlab.ui.container.Tab
        ResinmaterialpropertiesLabel    matlab.ui.control.Label
        Panel_23                        matlab.ui.container.Panel
        ViscosityPasEditField           matlab.ui.control.NumericEditField
        ViscosityPasLabel               matlab.ui.control.Label
        V12EditField_3                  matlab.ui.control.NumericEditField
        V12EditField_3Label             matlab.ui.control.Label
        E1PaEditField_3                 matlab.ui.control.NumericEditField
        E1PaEditField_3Label            matlab.ui.control.Label
        BIAXmaterialpropertiesdifferentmaterialLabel  matlab.ui.control.Label
        Panel_46                        matlab.ui.container.Panel
        G23PaEditField_4                matlab.ui.control.NumericEditField
        G23PaEditField_4Label           matlab.ui.control.Label
        G13PaEditField_4                matlab.ui.control.NumericEditField
        G13PaEditField_4Label           matlab.ui.control.Label
        G12PaEditField_4                matlab.ui.control.NumericEditField
        G12PaEditField_4Label           matlab.ui.control.Label
        V23EditField_4                  matlab.ui.control.NumericEditField
        V23EditField_4Label             matlab.ui.control.Label
        V13EditField_4                  matlab.ui.control.NumericEditField
        V13EditField_4Label             matlab.ui.control.Label
        V12EditField_5                  matlab.ui.control.NumericEditField
        V12EditField_5Label             matlab.ui.control.Label
        E3PaEditField_4                 matlab.ui.control.NumericEditField
        E3PaEditField_4Label            matlab.ui.control.Label
        E2PaEditField_4                 matlab.ui.control.NumericEditField
        E2PaEditField_4Label            matlab.ui.control.Label
        E1PaEditField_5                 matlab.ui.control.NumericEditField
        E1PaEditField_5Label            matlab.ui.control.Label
        UDmaterialpropertiesdifferentmaterialLabel  matlab.ui.control.Label
        Panel_45                        matlab.ui.container.Panel
        G23PaEditField_3                matlab.ui.control.NumericEditField
        G23PaEditField_3Label           matlab.ui.control.Label
        G13PaEditField_3                matlab.ui.control.NumericEditField
        G13PaEditField_3Label           matlab.ui.control.Label
        G12PaEditField_3                matlab.ui.control.NumericEditField
        G12PaEditField_3Label           matlab.ui.control.Label
        V23EditField_3                  matlab.ui.control.NumericEditField
        V23EditField_3Label             matlab.ui.control.Label
        V13EditField_3                  matlab.ui.control.NumericEditField
        V13EditField_3Label             matlab.ui.control.Label
        V12EditField_4                  matlab.ui.control.NumericEditField
        V12EditField_4Label             matlab.ui.control.Label
        E3PaEditField_3                 matlab.ui.control.NumericEditField
        E3PaEditField_3Label            matlab.ui.control.Label
        E2PaEditField_3                 matlab.ui.control.NumericEditField
        E2PaEditField_3Label            matlab.ui.control.Label
        E1PaEditField_4                 matlab.ui.control.NumericEditField
        E1PaEditField_4Label            matlab.ui.control.Label
        BIAXmaterialpropertiesLabel     matlab.ui.control.Label
        Panel_22                        matlab.ui.container.Panel
        G23PaEditField_2                matlab.ui.control.NumericEditField
        G23PaEditField_2Label           matlab.ui.control.Label
        G13PaEditField_2                matlab.ui.control.NumericEditField
        G13PaEditField_2Label           matlab.ui.control.Label
        G12PaEditField_2                matlab.ui.control.NumericEditField
        G12PaEditField_2Label           matlab.ui.control.Label
        V23EditField_2                  matlab.ui.control.NumericEditField
        V23EditField_2Label             matlab.ui.control.Label
        V13EditField_2                  matlab.ui.control.NumericEditField
        V13EditField_2Label             matlab.ui.control.Label
        V12EditField_2                  matlab.ui.control.NumericEditField
        V12EditField_2Label             matlab.ui.control.Label
        E3PaEditField_2                 matlab.ui.control.NumericEditField
        E3PaEditField_2Label            matlab.ui.control.Label
        E2PaEditField_2                 matlab.ui.control.NumericEditField
        E2PaEditField_2Label            matlab.ui.control.Label
        E1PaEditField_2                 matlab.ui.control.NumericEditField
        E1PaEditField_2Label            matlab.ui.control.Label
        UDmaterialpropertiesLabel       matlab.ui.control.Label
        Panel_21                        matlab.ui.container.Panel
        G23PaEditField                  matlab.ui.control.NumericEditField
        G23PaEditFieldLabel             matlab.ui.control.Label
        G13PaEditField                  matlab.ui.control.NumericEditField
        G13PaEditFieldLabel             matlab.ui.control.Label
        G12PaEditField                  matlab.ui.control.NumericEditField
        G12PaEditFieldLabel             matlab.ui.control.Label
        V23EditField                    matlab.ui.control.NumericEditField
        V23EditFieldLabel               matlab.ui.control.Label
        V13EditField                    matlab.ui.control.NumericEditField
        V13EditFieldLabel               matlab.ui.control.Label
        V12EditField                    matlab.ui.control.NumericEditField
        V12EditFieldLabel               matlab.ui.control.Label
        E3PaEditField                   matlab.ui.control.NumericEditField
        E3PaEditFieldLabel              matlab.ui.control.Label
        E2PaEditField                   matlab.ui.control.NumericEditField
        E2PaEditFieldLabel              matlab.ui.control.Label
        E1PaEditField                   matlab.ui.control.NumericEditField
        E1PaEditFieldLabel              matlab.ui.control.Label
        CohesivematerialpropertiesGeneralStaticAnalysisStepcontrolTab  matlab.ui.container.Tab
        StepcontrolequilibriumTimeincrementationLabel  matlab.ui.control.Label
        Panel_49                        matlab.ui.container.Panel
        IREditField                     matlab.ui.control.NumericEditField
        IREditFieldLabel                matlab.ui.control.Label
        IoEditField                     matlab.ui.control.NumericEditField
        IoEditFieldLabel                matlab.ui.control.Label
        DamagestabilizationLabel        matlab.ui.control.Label
        Panel_48                        matlab.ui.container.Panel
        StabilizationforcohesiveEditField  matlab.ui.control.NumericEditField
        StabilizationforcohesiveEditFieldLabel  matlab.ui.control.Label
        DamageinitiationelasticlimitPaCriterionMaxstressQUADSLabel  matlab.ui.control.Label
        Panel_29                        matlab.ui.container.Panel
        BIAX_DIFFEditField              matlab.ui.control.NumericEditField
        BIAX_DIFFEditFieldLabel         matlab.ui.control.Label
        UD_DIFFEditField                matlab.ui.control.NumericEditField
        UD_DIFFEditFieldLabel           matlab.ui.control.Label
        Resin3EditField_2               matlab.ui.control.NumericEditField
        Resin3EditField_2Label          matlab.ui.control.Label
        Resin2EditField_2               matlab.ui.control.NumericEditField
        Resin2EditField_2Label          matlab.ui.control.Label
        Resin1EditField_2               matlab.ui.control.NumericEditField
        Resin1EditField_2Label          matlab.ui.control.Label
        BIAXEditField                   matlab.ui.control.NumericEditField
        BIAXEditFieldLabel              matlab.ui.control.Label
        UDEditField                     matlab.ui.control.NumericEditField
        UDEditFieldLabel                matlab.ui.control.Label
        DamageevolutionBenzeggaghKenaneLabel  matlab.ui.control.Label
        Panel_32                        matlab.ui.container.Panel
        BIAX_DIFFEditField_2            matlab.ui.control.NumericEditField
        BIAX_DIFFEditField_2Label       matlab.ui.control.Label
        UD_DIFFEditField_2              matlab.ui.control.NumericEditField
        UD_DIFFEditField_2Label         matlab.ui.control.Label
        ResinEditField                  matlab.ui.control.NumericEditField
        ResinEditFieldLabel             matlab.ui.control.Label
        BIAXEditField_2                 matlab.ui.control.NumericEditField
        BIAXEditField_2Label            matlab.ui.control.Label
        UDEditField_2                   matlab.ui.control.NumericEditField
        UDEditField_2Label              matlab.ui.control.Label
        GeneralStaticAnalysisLabel      matlab.ui.control.Label
        Panel_47                        matlab.ui.container.Panel
        MaximumtimeincrementsEditField  matlab.ui.control.NumericEditField
        MaximumtimeincrementsEditFieldLabel  matlab.ui.control.Label
        MinimumtimeincrementsEditField  matlab.ui.control.NumericEditField
        MinimumtimeincrementsEditFieldLabel  matlab.ui.control.Label
        TimeperiodsEditField            matlab.ui.control.NumericEditField
        TimeperiodsEditFieldLabel       matlab.ui.control.Label
        ststepsEditField                matlab.ui.control.NumericEditField
        ststepsEditFieldLabel           matlab.ui.control.Label
        DamageevolutionFractureenergyJm2Label  matlab.ui.control.Label
        Panel_31                        matlab.ui.container.Panel
        BIAX3DIFFEditField              matlab.ui.control.NumericEditField
        BIAX3DIFFEditFieldLabel         matlab.ui.control.Label
        BIAX2DIFFEditField              matlab.ui.control.NumericEditField
        BIAX2DIFFEditFieldLabel         matlab.ui.control.Label
        BIAX1DIFFEditField              matlab.ui.control.NumericEditField
        BIAX1DIFFEditFieldLabel         matlab.ui.control.Label
        UD3DIFFEditField                matlab.ui.control.NumericEditField
        UD3DIFFEditFieldLabel           matlab.ui.control.Label
        UD2DIFFEditField                matlab.ui.control.NumericEditField
        UD2DIFFEditFieldLabel           matlab.ui.control.Label
        UD1DIFFEditField                matlab.ui.control.NumericEditField
        UD1DIFFEditFieldLabel           matlab.ui.control.Label
        BIAX3EditField                  matlab.ui.control.NumericEditField
        BIAX3EditFieldLabel             matlab.ui.control.Label
        BIAX2EditField                  matlab.ui.control.NumericEditField
        BIAX2EditFieldLabel             matlab.ui.control.Label
        BIAX1EditField                  matlab.ui.control.NumericEditField
        BIAX1EditFieldLabel             matlab.ui.control.Label
        UD3EditField                    matlab.ui.control.NumericEditField
        UD3EditFieldLabel               matlab.ui.control.Label
        UD2EditField                    matlab.ui.control.NumericEditField
        UD2EditFieldLabel               matlab.ui.control.Label
        UD1EditField                    matlab.ui.control.NumericEditField
        UD1EditFieldLabel               matlab.ui.control.Label
        Resin3EditField                 matlab.ui.control.NumericEditField
        Resin3EditFieldLabel            matlab.ui.control.Label
        Resin2EditField                 matlab.ui.control.NumericEditField
        Resin2EditFieldLabel            matlab.ui.control.Label
        Resin1EditField                 matlab.ui.control.NumericEditField
        Resin1EditFieldLabel            matlab.ui.control.Label
        LinearelastictractionseparationbehaviorLabel  matlab.ui.control.Label
        Panel_28                        matlab.ui.container.Panel
        EttPamEditField                 matlab.ui.control.NumericEditField
        EttPamEditFieldLabel            matlab.ui.control.Label
        EssPamEditField                 matlab.ui.control.NumericEditField
        EssPamEditFieldLabel            matlab.ui.control.Label
        EnnPamEditField                 matlab.ui.control.NumericEditField
        EnnPamEditFieldLabel            matlab.ui.control.Label
        RunOutputTab                    matlab.ui.container.Tab
        MENULabel                       matlab.ui.control.Label
        TotallengthoftherepairmmEditField  matlab.ui.control.NumericEditField
        TotallengthoftherepairmmLabel   matlab.ui.control.Label
        TotallengthofthecompositemmEditField  matlab.ui.control.NumericEditField
        TotallengthofthecompositemmLabel  matlab.ui.control.Label
        TotalthicknessmmEditField       matlab.ui.control.NumericEditField
        TotalthicknessmmEditFieldLabel  matlab.ui.control.Label
        ChooseplotDropDown              matlab.ui.control.DropDown
        ChooseplotDropDownLabel         matlab.ui.control.Label
        Panel_17                        matlab.ui.container.Panel
        DNmEditField                    matlab.ui.control.NumericEditField
        DNmEditFieldLabel               matlab.ui.control.Label
        EGpaEditField                   matlab.ui.control.NumericEditField
        EGpaEditFieldLabel              matlab.ui.control.Label
        RestartButton                   matlab.ui.control.Button
        TimesEditField                  matlab.ui.control.NumericEditField
        TimesEditFieldLabel             matlab.ui.control.Label
        JOBEditField                    matlab.ui.control.EditField
        JOBEditFieldLabel               matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        OutputStressStrainTab           matlab.ui.container.Tab
        JOBEditField_2                  matlab.ui.control.EditField
        JOBEditField_2Label             matlab.ui.control.Label
        Panel_20                        matlab.ui.container.Panel
        RM3NmEditField                  matlab.ui.control.NumericEditField
        RM3NmEditFieldLabel             matlab.ui.control.Label
        RF2NEditField                   matlab.ui.control.NumericEditField
        RF2NEditFieldLabel              matlab.ui.control.Label
        RF1NEditField                   matlab.ui.control.NumericEditField
        RF1NEditFieldLabel              matlab.ui.control.Label
        ReactionForcesLabel             matlab.ui.control.Label
        ChooseelementtypeDropDown       matlab.ui.control.DropDown
        ChooseelementtypeDropDownLabel  matlab.ui.control.Label
        Panel_19                        matlab.ui.container.Panel
        LE12EditField                   matlab.ui.control.NumericEditField
        LE12EditFieldLabel              matlab.ui.control.Label
        LE33EditField                   matlab.ui.control.NumericEditField
        LE33EditFieldLabel              matlab.ui.control.Label
        LE22EditField                   matlab.ui.control.NumericEditField
        LE22EditFieldLabel              matlab.ui.control.Label
        LE11EditField                   matlab.ui.control.NumericEditField
        LE11EditFieldLabel              matlab.ui.control.Label
        MaximumStrainLabel              matlab.ui.control.Label
        Panel_18                        matlab.ui.container.Panel
        S12MPaEditField                 matlab.ui.control.NumericEditField
        S12MPaEditFieldLabel            matlab.ui.control.Label
        S33MPaEditField                 matlab.ui.control.NumericEditField
        S33MPaEditFieldLabel            matlab.ui.control.Label
        S22MPaEditField                 matlab.ui.control.NumericEditField
        S22MPaEditFieldLabel            matlab.ui.control.Label
        S11MPaEditField                 matlab.ui.control.NumericEditField
        S11MPaLabel                     matlab.ui.control.Label
        MaximumStressLabel              matlab.ui.control.Label
        Guide1Tab                       matlab.ui.container.Tab
        Image                           matlab.ui.control.Image
        LoadingTextArea                 matlab.ui.control.TextArea
        LoadingTextAreaLabel            matlab.ui.control.Label
        LayupSelectionProcessTextArea   matlab.ui.control.TextArea
        LayupSelectionProcessTextAreaLabel  matlab.ui.control.Label
        DefinematerialthicknessTextArea  matlab.ui.control.TextArea
        DefinematerialthicknessTextAreaLabel  matlab.ui.control.Label
        OverlappinglayerlengthTextArea  matlab.ui.control.TextArea
        OverlappinglayerlengthTextAreaLabel  matlab.ui.control.Label
        OutputfilenameTextArea          matlab.ui.control.TextArea
        OutputfilenameTextAreaLabel     matlab.ui.control.Label
        InputFilenameTextArea           matlab.ui.control.TextArea
        InputFilenameTextAreaLabel      matlab.ui.control.Label
        Guide2Tab                       matlab.ui.container.Tab
        PlybyplymatchTextArea_2         matlab.ui.control.TextArea
        PlybyplymatchTextArea_2Label    matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        GaussianparametersguessTextArea  matlab.ui.control.TextArea
        GaussianparametersguessTextAreaLabel  matlab.ui.control.Label
        ElementnumberTextArea           matlab.ui.control.TextArea
        ElementnumberTextAreaLabel      matlab.ui.control.Label
        Geometryof2DmodelTextArea       matlab.ui.control.TextArea
        Geometryof2DmodelTextAreaLabel  matlab.ui.control.Label
        ResinElementTextArea            matlab.ui.control.TextArea
        ResinElementTextAreaLabel       matlab.ui.control.Label
        CurvepanelTextArea              matlab.ui.control.TextArea
        CurvepanelTextAreaLabel         matlab.ui.control.Label
        OverlaminaAdditionontopofrepairTextArea  matlab.ui.control.TextArea
        OverlaminaAdditionontopofrepairTextAreaLabel  matlab.ui.control.Label
        WidthofdefectTextArea           matlab.ui.control.TextArea
        WidthofdefectTextAreaLabel      matlab.ui.control.Label
        DiscretasingdensityTextArea     matlab.ui.control.TextArea
        DiscretasingdensityTextAreaLabel  matlab.ui.control.Label
    end


    properties (Access = public)
        % Description
        t_Vres
        t_overmat;
        ud;
        biax;
        width_char;
        x;
        yhat;
        x_store;
        y_store;
        x_aseb;
        y_aseb;
        x_hty;
        y_hty;
        xrep_right_aseb;
        yrep_right_aseb;
        xrep_left_aseb;
        yrep_left_aseb;
        x_right_par;
        y_right_par;
        x_left_par;
        y_left_par;
        x_rep;
        y_rep;
        X_res_Tot;
        yRes;
        Y_res_Tot;
        flag;
        xbiax_olap;
        xud_olap;
        layup;
        L_remrow;
        t_Hres;
        S11;
        S22;
        S33;
        S12;
        LE11;
        LE22;
        LE33;
        LE12;
        E1UD;
        E1BIAX;
        E2UD;
        E2BIAX;
        E3UD;
        E3BIAX;
        V12UD;
        V12BIAX;
        V13UD;
        V13BIAX;
        V23UD;
        V23BIAX;
        G12UD;
        G12BIAX;
        G13UD;
        G13BIAX;
        G23UD;
        G23BIAX;
        first_Step;
        Tperiod;
        Min_incr;
        Max_incr;
        Viscosity;
        E1RESIN;
        V12RESIN;
        E1UD_EXTRA;
        E1BIAX_EXTRA;
        E2UD_EXTRA;
        E2BIAX_EXTRA;
        E3UD_EXTRA;
        E3BIAX_EXTRA;
        V12UD_EXTRA;
        V12BIAX_EXTRA;
        V13UD_EXTRA;
        V13BIAX_EXTRA;
        V23UD_EXTRA;
        V23BIAX_EXTRA;
        G12UD_EXTRA;
        G12BIAX_EXTRA;
        G13UD_EXTRA;
        G13BIAX_EXTRA;
        G23UD_EXTRA;
        G23BIAX_EXTRA;
        
        % Damage initiation
        Daminit_UD; 
        Daminit_UD_DIFF;
        Daminit_BIAX;
        Daminit_BIAX_DIFF;
        Daminit_Resin1;
        Daminit_Resin2;
        Daminit_Resin3;

        % Damage evolution
        Damev_UD;
        Damev_BIAX;
        Damev_Resin;
        Damev_UD_DIFF;
        Damev_BIAX_DIFF;
        
        % Fracture energy
        Frac_UD1;
        Frac_UD2;
        Frac_UD3;
        Frac_BIAX1;
        Frac_BIAX2;
        Frac_BIAX3;
        Frac_Resin1;
        Frac_Resin2;
        Frac_Resin3;
        Frac_UD1_DIFF;
        Frac_UD2_DIFF;
        Frac_UD3_DIFF;
        Frac_BIAX1_DIFF;
        Frac_BIAX2_DIFF;
        Frac_BIAX3_DIFF;
        
        % Elastic behavior
        Knn;
        Kss;
        Ktt;
        
        % Damage stabilization
        Damstab;
        
        % Time incrementation
        I_o;
        I_R;
        
        % displacement
        U1;
        RF1;


    end

    methods (Access = private)


    end

    methods (Access = public)

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %clc; fprintf('Beginning to run %s.m.\r\n', mfilename); close all; clear;
            %clear global; %workspace; format long g; format compact;
            % resize the screen
              screenSize = get(groot,'ScreenSize');
              screenWidth = screenSize(3);
              screenHeight = screenSize(4);
              left = screenWidth*0.1;
              bottom = screenHeight*0.1;
              width = screenWidth*0.8;
              height = screenHeight*0.8;
              drawnow;
              app.UIFigure.Position = [left bottom width height]; 
          
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Change the JOB state
            tic;
            % Change the JOB state
            %app.JOBEditField.Value = 'Running MPSS';
            %app.JOBEditField.Value = 'Running';

            %% Read all the variables and name them as in Aura's code and make public the necesasary ones

            % Resin
            app.t_Hres = app.ThicknessofresininterfaceinbetweenlayersmmEditField.Value;
            t_ud = app.ThicknessofUDfibrelaminammEditField.Value;

            % UD
            app.ud = t_ud + app.t_Hres;

            t_biax = app.ThicknessofBIAXfibrelaminammEditField.Value;

            % BIAX
            app.biax = t_biax + app.t_Hres;


            % Thickness of resin in between repair and parent laminate
            app.t_Vres = app.ThicknessofresininbetweenrepairandparentlaminatemmEditField.Value;

            % Thickness of the overlaminate biax or ud
            t_overmat_label = app.ThicknessoftheoverlaminabiaxorudEditField.Value;

            % Convert the label into a value for the  thickness overmat
            if strcmp(t_overmat_label,'ud')
                app.t_overmat = app.ud;
                %app.number2EditField.Value = t_overmat;
            elseif strcmp(t_overmat_label,'biax')
                app.t_overmat = app.biax;
            end

            %% Flag options
            flag_option = app.FlagDropDown.Value;
            % Convert the option into a value into flag value
            if strcmp(flag_option,'0: Composite with defect and layup contains two different lamina materials')
                app.flag = 0;

            elseif strcmp(flag_option,'1: Composite with defect and layup contains one type of lamina material')
                app.flag = 1;
            elseif strcmp(flag_option,'2: Composite without defect and layup contains two different lamina materials')
                app.flag = 2;
            elseif strcmp(flag_option,'3: Composite without defect and layup contains one type of lamina material')
                app.flag = 3;
            end

            %% Layupdefects
            % Convert input for vectors
            % layup_defect = str2double(strsplit(layup_defect_notconverted,' '));
            % For strings underneath

            layup_defect_notconverted = app.LayupwithdefectegudbiaxudEditField.Value;
            layup_defect_char = (strsplit(layup_defect_notconverted,','));
            a = size(layup_defect_char);
            for layup1=1:a(2)
                if strcmp(layup_defect_char{1,layup1},'ud')
                    layup_defect(layup1) = app.ud;
                elseif strcmp(layup_defect_char{1,layup1},'biax')
                    layup_defect(layup1) = app.biax;
                end
            end
            layup_defect = layup_defect';

            layup_nodefect_notconverted = app.LayupwithoutdefectegudbiaxudEditField.Value;
            layup_nodefect_char = (strsplit(layup_nodefect_notconverted,','));
            b = size(layup_nodefect_char);
            for layup2=1:b(2)
                if strcmp(layup_nodefect_char{1,layup2},'ud')
                    layup_nodefect(layup2) = app.ud;
                elseif strcmp(layup_nodefect_char{1,layup2},'biax')
                    layup_nodefect(layup2) = app.biax;
                end
            end
            layup_nodefect = layup_nodefect';

            %% Parent material
            matrep_option = app.ParentmaterialDropDown.Value;
            % Convert the option into a value into matrep value
            if strcmp(matrep_option,'0: with the same material in repair and parent')
                matrep = 0;
            elseif strcmp(matrep_option,'1: with different material')
                matrep = 1;
            end

            %% Loading
            Loading_option = app.LoadingDropDown.Value;
            % Convert the option into a value into loading value
            if strcmp(Loading_option,'0: Tension')
                Loading = 0;
            elseif strcmp(Loading_option,'1: Bending')
                Loading = 1;
            end
            x_disp    = app.U1DisplacementinXmmEditField_2.Value;  % U1 displacement [mm]
            theta_rot = app.UR3rotationaldisplacementradEditField_2.Value; % UR3 rotational displacement [rad]

            %% Output file name
            namefile = app.FilenameEditField.Value;
            namefileinp = '.inp';
            fileID = fopen(strcat(namefile,namefileinp), 'w');

            %% Resin Element
            resinEl_option = app.TypeofelementDropDown.Value;
            % Convert the option into a value into resinEl value
            if strcmp(resinEl_option,'0: Homogeneous elements')
                resinEl = 0;
            elseif strcmp(resinEl_option,'1: Cohesive elements')
                resinEl = 1;
            end

            %% Overlapping layer length
            app.xbiax_olap = app.BIAXoverlapmmEditField.Value;            % [mm]
            app.xud_olap   = app.UDoverlapmmEditField.Value;              % [mm]
            xover_olap = app.OverlaminaoverlapmmEditField.Value;      % Overlap length of overlamina [mm]

            %% Discretising density (dependent on how defined ABAQUS element)
            disc_density = app.DiscretisingdensityEditField.Value;

            %% Width of defect
            x_defect = app.WidthofthedefectmmEditField.Value;                    % [mm]

            %% Geometry of 2D model
            x_width = app.WidthfrommiddleofdefectmmEditField.Value;                   % [mm] width from middle of defect
            x_al    = app.AdditionalallowanceattheedgesmmEditField.Value;                    % [mm] Additional allowance at the edges

            %% Ply by ply match
            mat_change_option = app.PlymatchDropDown.Value;  % 0: ply by ply match, 1: ply by ply mismatch
            % Convert the option into a value into resinEl value
            if strcmp(mat_change_option,'0: ply by ply match')
                mat_change = 0;
            elseif strcmp(mat_change_option,'1: ply by ply mismatch')
                mat_change = 1;
            end

            %% Overlamina Addition on top of repair
            % adding laminate
            overlam = app.OverlaminaDropDown.Value;
            if strcmp(overlam,'No')
                over_lam   = 0;
            elseif strcmp(overlam,'Yes')
                over_lam   = 1;
            end
            % adding length on the overlaminate
            overolap = app.OverlappingoftheoverlaminaDropDown.Value;
            if strcmp(overolap,'No')
                over_olap   = 0;
            elseif strcmp(overolap,'Yes')
                over_olap   = 1;
            end

            %% Element number
            % NOTE: there is a limit of the combination of number,
            % since it is dependendent on disc_density (if layupdefect is 3, then width
            % has 7 inputs, since 3*2 + 1 =7) e.g.width_vec   = [  80 45 65 45 45 150 45 45 65 45 80 ];
            width_notconverted = app.WidthvectorEditField.Value;
            app.width_char = (strsplit(width_notconverted,','));
            for width2 = 1:length(app.width_char)
                width_vec(width2) = str2num(cell2mat(app.width_char(width2)));
            end

            %% Curve panel
            curve_panel = app.CurveDropDown.Value;
            if strcmp(curve_panel,'No')
                curve   = 0;
            elseif strcmp(curve_panel,'Yes')
                curve   = 1;
            end
            a_curve = app.ParabolacurveEditField.Value; % parabola curve e.g = 200

            %% Guess values for Gaussian parameters
            amplitude_guess = app.amplitudeEditField.Value; % a: also called as global 'c' e.g = 8
            centre_guess = app.centreEditField.Value; % b: peak position e.g = 200
            sigma_guess = app.sigmaEditField.Value;   % c: width e.g = 8

            %% Material properties input
            % UD
            app.E1UD = app.E1PaEditField.Value;
            app.E2UD = app.E2PaEditField.Value;
            app.E3UD = app.E3PaEditField.Value;
            app.V12UD = app.V12EditField.Value;
            app.V13UD = app.V13EditField.Value;
            app.V23UD = app.V23EditField.Value;
            app.G12UD = app.G12PaEditField.Value;
            app.G13UD = app.G13PaEditField.Value;
            app.G23UD = app.G23PaEditField.Value;

            % BIAX
            app.E1BIAX = app.E1PaEditField_2.Value;
            app.E2BIAX = app.E2PaEditField_2.Value;
            app.E3BIAX = app.E3PaEditField_2.Value;
            app.V12BIAX = app.V12EditField_2.Value;
            app.V13BIAX = app.V13EditField_2.Value;
            app.V23BIAX = app.V23EditField_2.Value;
            app.G12BIAX = app.G12PaEditField_2.Value;
            app.G13BIAX = app.G13PaEditField_2.Value;
            app.G23BIAX = app.G23PaEditField_2.Value;

            % Resin
            app.Viscosity = app.ViscosityPasEditField.Value;
            app.E1RESIN = app.E1PaEditField_3.Value;
            app.V12RESIN = app.V12EditField_3.Value;

            % Extra material input UD
            app.E1UD_EXTRA = app.E1PaEditField_4.Value;
            app.E2UD_EXTRA = app.E2PaEditField_3.Value;
            app.E3UD_EXTRA = app.E3PaEditField_3.Value;
            app.V12UD_EXTRA = app.V12EditField_4.Value;
            app.V13UD_EXTRA  = app.V13EditField_3.Value;
            app.V23UD_EXTRA = app.V23EditField_3.Value;
            app.G12UD_EXTRA = app.G12PaEditField_3.Value;
            app.G13UD_EXTRA = app.G13PaEditField_3.Value;
            app.G23UD_EXTRA = app.G23PaEditField_3.Value;
            
            % Extra material input
            app.E1BIAX_EXTRA = app.E1PaEditField_5.Value;
            app.E2BIAX_EXTRA = app.E2PaEditField_4.Value;
            app.E3BIAX_EXTRA = app.E3PaEditField_4.Value;
            app.V12BIAX_EXTRA = app.V12EditField_5.Value;            
            app.V13BIAX_EXTRA = app.V13EditField_4.Value;            
            app.V23BIAX_EXTRA = app.V23EditField_4.Value;            
            app.G12BIAX_EXTRA = app.G12PaEditField_4.Value;            
            app.G13BIAX_EXTRA = app.G13PaEditField_4.Value;
            app.G23BIAX_EXTRA = app.G23PaEditField_4.Value;

            % Step data
            app.first_Step = app.ststepsEditField.Value;
            app.Tperiod = app.TimeperiodsEditField.Value;
            app.Min_incr = app.MinimumtimeincrementsEditField.Value;
            app.Max_incr = app.MaximumtimeincrementsEditField.Value;
            
            % Time incrementation
            app.I_o = app.IoEditField.Value;
            app.I_R = app.IREditField.Value;

            % Number of cores
            N_cores = app.CPUcoresEditField.Value;

            % Cohesive  element input
            % Damage initiation
            app.Daminit_UD = app.UDEditField.Value;
            app.Daminit_UD_DIFF = app.UD_DIFFEditField.Value;
            app.Daminit_BIAX = app.BIAXEditField.Value;
            app.Daminit_BIAX_DIFF = app.BIAX_DIFFEditField.Value;
            app.Daminit_Resin1 = app.Resin1EditField_2.Value;
            app.Daminit_Resin2 = app.Resin2EditField_2.Value;
            app.Daminit_Resin3 = app.Resin3EditField_2.Value;

            % Damage evolution
            app.Damev_UD = app.UDEditField_2.Value;
            app.Damev_BIAX = app.BIAXEditField_2.Value;
            app.Damev_Resin = app.ResinEditField.Value;
            app.Damev_UD_DIFF = app.UD_DIFFEditField_2.Value;
            app.Damev_BIAX_DIFF = app.BIAX_DIFFEditField_2.Value;

            % Fracture energy
            app.Frac_UD1 = app.UD1EditField.Value;
            app.Frac_UD2 = app.UD2EditField.Value;
            app.Frac_UD3 = app.UD3EditField.Value;
            app.Frac_BIAX1 = app.BIAX1EditField.Value;
            app.Frac_BIAX2 = app.BIAX2EditField.Value;
            app.Frac_BIAX3 = app.BIAX3EditField.Value;
            app.Frac_Resin1 = app.Resin1EditField.Value;
            app.Frac_Resin2 = app.Resin2EditField.Value;
            app.Frac_Resin3 = app.Resin3EditField.Value;
            app.Frac_UD1_DIFF = app.UD1DIFFEditField.Value;
            app.Frac_UD2_DIFF = app.UD2DIFFEditField.Value;
            app.Frac_UD3_DIFF = app.UD3DIFFEditField.Value;
            app.Frac_BIAX1_DIFF = app.BIAX1DIFFEditField.Value;
            app.Frac_BIAX2_DIFF = app.BIAX2DIFFEditField.Value;
            app.Frac_BIAX3_DIFF = app.BIAX3DIFFEditField.Value;
            
            % Elastic behavior
            app.Knn = app.EnnPamEditField.Value;
            app.Kss = app.EssPamEditField.Value;
            app.Ktt = app.EttPamEditField.Value;
            
            % Damage stabilization
            app.Damstab = app.StabilizationforcohesiveEditField.Value;
            
            %% PART1: Data processing of Defect
            layup_nodefect = [layup_nodefect; layup_nodefect(end)];
            app.layup          = [ flip(layup_nodefect); flip(layup_defect) ];
            Nber_defect    = numel(layup_defect);
            Nber_nodefect  = numel(layup_nodefect);

            % Input Data Defect
            filename = app.FilenameEditField_2.Value;% e.g = 'Data/CASE1.xlsx'
            sheet = app.SheetEditField.Value;% e.g = 1
            Wrinkles = xlsread(filename, sheet);
            x_data   = Wrinkles(:,1)'; y_data = Wrinkles(:,2)';
            [Wrinkle, Tot_layup, Y_data, X_data] = A_dataprocess(app.flag, layup_defect,layup_nodefect,Nber_defect, x_width, x_al, x_data, y_data, app.t_Hres);

            %% PART 2: Start loop for defect creation interpolation and create defect according to number of layers with defect
            if (app.flag == 0) || (app.flag == 1)
                [app.x_store, app.y_store, app.x, app.yhat] = B_peakfitting_for_GUI(app.flag, Wrinkle, Nber_defect, disc_density, centre_guess, sigma_guess, amplitude_guess, layup_defect, app.t_Hres);
                % Save in order to load later on to decrease running time for high dis_density
                % save('Data/Gauss_inputCase1.mat','x_store','y_store')
                % load Data/Gauss_inputCase1.mat;
                % Check if process is paused

                %% PART 3: Create healthy layers below defected layers by extending the layers according to U-def by knowing the last bottom lamina with defect
                [app.x_aseb, app.y_aseb, app.x_hty, app.y_hty] = C_healthylayers(app.x_store, app.y_store, layup_nodefect, layup_defect);

                %% PART 4: Detect defect by locating mid-x_axis of defect, then start defect removal in right section, then take size of removal in right and use it for left section, since it is symmetric
                [app.xrep_left_aseb, app.yrep_left_aseb, app.xrep_right_aseb, app.yrep_right_aseb,app.x_right_par, app.y_right_par, app.x_left_par, app.y_left_par, app.L_remrow] = D_removedefect(app.layup, app.flag,app.x_aseb, app.y_aseb, x_defect, Nber_nodefect, layup_defect, app.ud, app.biax, app.xud_olap, app.xbiax_olap);

                %% PART 5: Create repair layers by combining left and right 'aseb' matrix
                [app.x_rep, app.y_rep, Y_top] = E_repairlayers(app.yrep_left_aseb, app.xrep_left_aseb, app.yrep_right_aseb, app.xrep_right_aseb, app.x_hty, app.y_right_par, app.y_left_par, app.L_remrow);

                %% PART 6: Contruct resin in between parent and repair laminate
                [xLsec, xRsec, xRsecEnd, Xad_left, Xad_right] = F_resinParRep(app.L_remrow, app.x_left_par, app.y_left_par,app.x_right_par, app.y_right_par, app.t_Vres, Tot_layup, app.x_rep, app.y_rep);

                %% PART 7: For element width change to with coarse to fine element widths
                Y_res_tot = [app.y_hty; Y_top];  % assemble y values of all composite
                [app.X_res_Tot, app.Y_res_Tot] = G_elementchange(width_vec, Tot_layup, xLsec, xRsec, xRsecEnd, Xad_left, Xad_right, Y_res_tot);

                %% PART 8: Adding curvature if requested
                if curve == 1 % with curvature in panel
                    [Ycurve_aseb] = H_curvature(app.X_res_Tot, app.Y_res_Tot, a_curve);

                    %% PART 9: Tracing all x and y to for parent and resins, etc. It is needed to make nodes and elements
                    [Xres_el, Yres_el, rep_lay, parTrR, parTrL, hty_lay] = I_trace(app.X_res_Tot, Ycurve_aseb, Xad_left, Xad_right, Nber_defect,Nber_nodefect, app.flag);

                    %% PART 10: Adding over laminate
                    if (over_lam == 1)
                        [x_res_Tot, y_res_Tot, y_over, xy_over] = H_overlaminate(app.X_res_Tot, Ycurve_aseb, app.t_overmat, app.t_Hres, over_olap, rep_lay, xover_olap);
                    else if (over_lam == 0)
                            x_res_Tot = app.X_res_Tot; y_res_Tot = Ycurve_aseb;
                    end
                    end

                    %% Part 11: Insert interface layer at spanwise horizontal direction
                    [X_Tot, Y_Tot, xRes, app.yRes] = J_resinInterface(app.t_Hres, x_res_Tot, y_res_Tot, over_lam, app.t_overmat);

                else if curve == 0 % Without curvature
                        [Xres_el, Yres_el, rep_lay, parTrR, parTrL, hty_lay] = I_trace(app.X_res_Tot, app.Y_res_Tot, Xad_left, Xad_right, Nber_defect,Nber_nodefect, app.flag);
                        if (over_lam == 1)
                            [x_res_Tot, y_res_Tot, y_over, xy_over] = H_overlaminate(app.X_res_Tot, app.Y_res_Tot, app.t_overmat, app.t_Hres, over_olap, rep_lay, xover_olap);
                        else if (over_lam == 0)
                                x_res_Tot = app.X_res_Tot; y_res_Tot = app.Y_res_Tot;
                        end
                        end
                        % Add Resin interface
                        [X_Tot, Y_Tot, xRes, app.yRes] = J_resinInterface(app.t_Hres, x_res_Tot, y_res_Tot, over_lam, app.t_overmat);
                end
                end

            else if (app.flag == 2) || (app.flag == 3)
                    [app.x_aseb, app.y_aseb, app.X_res_Tot, app.Y_res_Tot] = nodefect(layup_nodefect,layup_defect, Nber_nodefect, Nber_defect, disc_density, app.t_Hres, x_width, x_al, x_data, y_data);
                    if curve == 1 % with curvature in panel
                        [Ycurve_aseb] = H_curvature(app.X_res_Tot, app.Y_res_Tot, a_curve);
                        [X_Tot, Y_Tot, xRes, app.yRes] = J_resinInterface(app.t_Hres, app.X_res_Tot, Ycurve_aseb, over_lam, app.t_overmat);
                    else if curve == 0 % Without curvature
                            [X_Tot, Y_Tot, xRes, app.yRes] = J_resinInterface(app.t_Hres, app.X_res_Tot, app.Y_res_Tot, over_lam, app.t_overmat);
                    end
                    end
            end
            end
            % Change the JOB state
            % app.JOBEditField.Value = 'Running';
            % 1st plot to see that the job is done
            plot(app.UIAxes,app.x,app.yhat,'.r','LineWidth',1,'MarkerSize',11);
            title(app.UIAxes,'Wrinkle interpolation curve');
            xlabel(app.UIAxes,'length / mm','Interpreter','latex','Fontsize',13);
            ylabel(app.UIAxes,'thickness / mm','Interpreter','latex','Fontsize',13);
            %set(app.UIAxes,'position',[x0,y0,width,height])
            %xlim(app.UIAxes,sort([app.x(1) app.x(end)]));

            %% Print values
            if (app.flag==0)
                formatSpec = 'Repaired Composite Panel with UD and BIAX material \r\n';
                fprintf(formatSpec);
            else if (app.flag==1)
                    formatSpec = 'Repaired Composite Panel with one material \r\n';
                    fprintf(formatSpec);
            else if (app.flag==2)
                    formatSpec = 'Non-Repaired Composite Panel with UD and BIAX material \r\n';
                    fprintf(formatSpec);
            else if (app.flag==3)
                    formatSpec = 'Non-Repaired Composite Panel with one material \r\n';
                    fprintf(formatSpec);
            end
            end
            end
            end

            Tot_thick = Y_Tot(end,1);
            formatSpec = 'Total thickness of composite is %4.2f mm \r\n';
            fprintf(formatSpec,Tot_thick);
            app.TotalthicknessmmEditField.Value = Tot_thick;

            Tot_length = X_Tot(1,end);
            formatSpec = 'Total length of composite is %4.2f mm \r\n';
            fprintf(formatSpec,Tot_length);
            app.TotallengthofthecompositemmEditField.Value = Tot_length;

            if (app.flag == 0) || (app.flag == 1)
                Tot_repair = rep_lay{end,1}(end,1) - rep_lay{end,1}(1,1);
                formatSpec = 'Total length of repair is %4.2f mm \r\n';
                fprintf(formatSpec,Tot_repair);
                app.TotallengthoftherepairmmEditField.Value = Tot_repair;
            end
            %% Part 12: ABAQUS Results processing for meshing

            % Prepare input for abaqus inp file
            LineNberExt = size(X_Tot,1); % y-direction
            X_nodenber  = length(X_Tot);  % x-direction
            x_extTot    = cell(1, LineNberExt - 1);
            y_extTot    = cell(1, LineNberExt - 1);
            NberNodef   = Nber_nodefect - 1; % -1 to eliminate the dummy layer
            nberrep     = (NberNodef + (NberNodef + 1)); % Number of nodefect layers + Number of resin layers wrt to the next layer
            LayerName   = (0:LineNberExt-1)';
            ResinName   = (0:2:LineNberExt-1)';
            LayupMat    = [flip(layup_nodefect(1:end-1,:)); flip(layup_defect)];
            countLay    = 1;

            for i11 = 1 : LineNberExt
                x_extTot{1, countLay} = X_Tot(i11, :); % bottom to top
                y_extTot{1, countLay} = Y_Tot(i11, :); % bottom to top
                countLay = countLay + 1;
            end

            % Nodes
            x_mat = cell2mat(x_extTot)'/1000; % convert cell data to matrix
            y_mat = cell2mat(y_extTot)'/1000;
            TotNode  = (LineNberExt) * X_nodenber; % Total node number
            NodeNber = cell(TotNode, 1);

            % Text formatting
            FormatY = 2; FormatX = 4;  % 2 digits for layer number , 4 digits for number of points depending on disc_density

            % Syntax
            NberFormaty = ['%0', num2str(FormatY), '.0f']; NberFormatx = ['%0', num2str(FormatX), '.0f'];

            % Assign node number to each node
            countNber = 1;
            for i6 = 1:LineNberExt
                for i7 = 1 : X_nodenber
                    NodeNber{countNber, 1} = [num2str(i6, NberFormaty), num2str(i7, NberFormatx)];
                    countNber = countNber + 1;
                end
            end
            %% Print Abaqus input file according to case
            flag = app.flag;
            ud = app.ud;
            biax = app.biax;

            % delete the old .inp file
            namefileinp = '.inp';
            delete(strcat(namefile,namefileinp));
            
            % With same material in repair and parent laminate
            if (matrep == 0)
                % With repair case
                if (app.flag == 0) || (app.flag == 1)
                    save inputwithrepair.mat
                    if (Loading == 0) % Tension Case
                        if (resinEl == 1)
                            % app.E1RESIN = app.E1PaEditField_3.Value;
                            % app.V12RESIN = app.V12EditField_3.Value;

                            K_abaqusrepairCZM_functionCPE4_2(app.V12RESIN,app.E1RESIN,app.I_o,app.I_R,app.Damstab,app.t_Vres,app.flag, app.layup,app.t_Hres,app.t_overmat,app.E1UD,app.E1BIAX,app.E2UD,app.E2BIAX,app.E3UD,app.E3BIAX,app.V12UD,app.V12BIAX,app.V13UD,app.V13BIAX,app.V23UD,app.V23BIAX,app.G12UD,app.G12BIAX,app.G13UD,app.G13BIAX,app.G23UD,app.G23BIAX,app.first_Step,app.Tperiod,app.Min_incr,app.Max_incr,app.Viscosity,app.Daminit_UD,app.Daminit_UD_DIFF,app.Daminit_BIAX,app.Daminit_BIAX_DIFF,app.Daminit_Resin1,app.Daminit_Resin2,app.Daminit_Resin3,app.Damev_UD,app.Damev_BIAX,app.Damev_Resin,app.Frac_UD1,app.Frac_UD2,app.Frac_UD3,app.Frac_BIAX1,app.Frac_BIAX2,app.Frac_BIAX3,app.Frac_Resin1,app.Frac_Resin2,app.Frac_Resin3,app.Frac_UD1_DIFF,app.Frac_UD2_DIFF,app.Frac_UD3_DIFF,app.Frac_BIAX1_DIFF,app.Frac_BIAX2_DIFF,app.Frac_BIAX3_DIFF,app.Knn,app.Kss,app.Ktt); % Resin modelled with cohesive elements
                        else if (resinEl == 0)
                                K_abaqusrepairSolid_function(app.flag, app.layup,app.t_Hres,app.t_overmat,app.E1UD,app.E1BIAX,app.E2UD,app.E2BIAX,app.E3UD,app.E3BIAX,app.V12UD,app.V12BIAX,app.V13UD,app.V13BIAX,app.V23UD,app.V23BIAX,app.G12UD,app.G12BIAX,app.G13UD,app.G13BIAX,app.G23UD,app.G23BIAX,app.first_Step,app.Tperiod,app.Min_incr,app.Max_incr,app.Viscosity,app.E1RESIN,app.V12RESIN); % Resin modelled with solid elements
                        end
                        end
                    else if (Loading == 1) % Bending case
                            if (resinEl == 1)
                                L_abaqusrepairCZM_function(app.V12RESIN,app.E1RESIN,app.I_o,app.I_R,app.Damstab,app.t_Vres,app.flag, app.layup,app.t_Hres,app.t_overmat,app.E1UD,app.E1BIAX,app.E2UD,app.E2BIAX,app.E3UD,app.E3BIAX,app.V12UD,app.V12BIAX,app.V13UD,app.V13BIAX,app.V23UD,app.V23BIAX,app.G12UD,app.G12BIAX,app.G13UD,app.G13BIAX,app.G23UD,app.G23BIAX,app.first_Step,app.Tperiod,app.Min_incr,app.Max_incr,app.Viscosity,app.Daminit_UD,app.Daminit_UD_DIFF,app.Daminit_BIAX,app.Daminit_BIAX_DIFF,app.Daminit_Resin1,app.Daminit_Resin2,app.Daminit_Resin3,app.Damev_UD,app.Damev_BIAX,app.Damev_Resin,app.Frac_UD1,app.Frac_UD2,app.Frac_UD3,app.Frac_BIAX1,app.Frac_BIAX2,app.Frac_BIAX3,app.Frac_Resin1,app.Frac_Resin2,app.Frac_Resin3,app.Frac_UD1_DIFF,app.Frac_UD2_DIFF,app.Frac_UD3_DIFF,app.Frac_BIAX1_DIFF,app.Frac_BIAX2_DIFF,app.Frac_BIAX3_DIFF,app.Knn,app.Kss,app.Ktt); % Resin modelled with cohesive elements
                            else if (resinEl == 0)
                                    L_abaqusrepairSolid_function(app.flag, app.layup,app.t_Hres,app.t_overmat,app.E1UD,app.E1BIAX,app.E2UD,app.E2BIAX,app.E3UD,app.E3BIAX,app.V12UD,app.V12BIAX,app.V13UD,app.V13BIAX,app.V23UD,app.V23BIAX,app.G12UD,app.G12BIAX,app.G13UD,app.G13BIAX,app.G23UD,app.G23BIAX,app.first_Step,app.Tperiod,app.Min_incr,app.Max_incr,app.Viscosity,app.E1RESIN,app.V12RESIN); % Resin modelled with solid elements
                            end
                            end
                    end
                    end
                end
        

                % Without repair case
                app.flag = flag;
                if (app.flag == 2) || (app.flag == 3)
                    save inputwithoutrepair.mat
                    if (Loading == 0) % Tension Case
                        if  (resinEl == 1)
                            K_abaquspristineCZM_function(app.I_o,app.I_R,app.Damstab,app.flag, app.layup,app.t_Hres,app.t_overmat,app.E1UD,app.E1BIAX,app.E2UD,app.E2BIAX,app.E3UD,app.E3BIAX,app.V12UD,app.V12BIAX,app.V13UD,app.V13BIAX,app.V23UD,app.V23BIAX,app.G12UD,app.G12BIAX,app.G13UD,app.G13BIAX,app.G23UD,app.G23BIAX,app.first_Step,app.Tperiod,app.Min_incr,app.Max_incr,app.Viscosity,app.Daminit_UD,app.Daminit_UD_DIFF,app.Daminit_BIAX,app.Daminit_BIAX_DIFF,app.Daminit_Resin1,app.Daminit_Resin2,app.Daminit_Resin3,app.Damev_UD,app.Damev_BIAX,app.Damev_Resin,app.Frac_UD1,app.Frac_UD2,app.Frac_UD3,app.Frac_BIAX1,app.Frac_BIAX2,app.Frac_BIAX3,app.Frac_Resin1,app.Frac_Resin2,app.Frac_Resin3,app.Frac_UD1_DIFF,app.Frac_UD2_DIFF,app.Frac_UD3_DIFF,app.Frac_BIAX1_DIFF,app.Frac_BIAX2_DIFF,app.Frac_BIAX3_DIFF,app.Knn,app.Kss,app.Ktt); % Resin modelled with cohesive elements
                        else if (resinEl == 0)
                                K_abaquspristineSolid_function(app.flag, app.layup,app.t_Hres,app.t_overmat,app.E1UD,app.E1BIAX,app.E2UD,app.E2BIAX,app.E3UD,app.E3BIAX,app.V12UD,app.V12BIAX,app.V13UD,app.V13BIAX,app.V23UD,app.V23BIAX,app.G12UD,app.G12BIAX,app.G13UD,app.G13BIAX,app.G23UD,app.G23BIAX,app.first_Step,app.Tperiod,app.Min_incr,app.Max_incr,app.Viscosity,app.E1RESIN,app.V12RESIN); % Resin modelled with solid elements
                        end
                        end
                    else if (Loading == 1) % Bending case
                            if  (resinEl == 1)
                                L_abaquspristineCZM_function(app.I_o,app.I_R,app.Damstab,app.t_Vres,app.flag, app.layup,app.t_Hres,app.t_overmat,app.E1UD,app.E1BIAX,app.E2UD,app.E2BIAX,app.E3UD,app.E3BIAX,app.V12UD,app.V12BIAX,app.V13UD,app.V13BIAX,app.V23UD,app.V23BIAX,app.G12UD,app.G12BIAX,app.G13UD,app.G13BIAX,app.G23UD,app.G23BIAX,app.first_Step,app.Tperiod,app.Min_incr,app.Max_incr,app.Viscosity); % Resin modelled with cohesive elements
                            else if (resinEl == 0)
                                    L_abaquspristineSolid_function(app.flag, app.layup,app.t_Hres,app.t_overmat,app.E1UD,app.E1BIAX,app.E2UD,app.E2BIAX,app.E3UD,app.E3BIAX,app.V12UD,app.V12BIAX,app.V13UD,app.V13BIAX,app.V23UD,app.V23BIAX,app.G12UD,app.G12BIAX,app.G13UD,app.G13BIAX,app.G23UD,app.G23BIAX,app.first_Step,app.Tperiod,app.Min_incr,app.Max_incr,app.Viscosity,app.E1RESIN,app.V12RESIN); % Resin modelled with solid elements
                            end
                            end
                    end
                    end
                end
%Daminit_UD,Daminit_UD_DIFF,Daminit_BIAX,Daminit_BIAX_DIFF,Daminit_Resin1,Daminit_Resin2,Daminit_Resin3,Damev_UD,Damev_BIAX,Damev_Resin,Frac_UD1,Frac_UD2,Frac_UD3,Frac_BIAX1,Frac_BIAX2,Frac_BIAX3,Frac_Resin1,Frac_Resin2,Frac_Resin3,Frac_UD1_DIFF,Frac_UD2_DIFF,Frac_UD3_DIFF,Frac_BIAX1_DIFF,Frac_BIAX2_DIFF,Frac_BIAX3_DIFF,Knn,Kss,Ktt
                % With Different material in repair
            else if (matrep == 1)
                    save inputwithrepair.mat
                    if (Loading == 0) % Tension Case
                        if (resinEl == 1)
                            K_abaqusmatdiffCZM_function(app.V12RESIN,app.E1RESIN,app.I_o,app.I_R,app.Damstab,app.t_Vres,app.flag, app.layup,app.t_Hres,app.t_overmat,app.E1UD,app.E1BIAX,app.E2UD,app.E2BIAX,app.E3UD,app.E3BIAX,app.V12UD,app.V12BIAX,app.V13UD,app.V13BIAX,app.V23UD,app.V23BIAX,app.G12UD,app.G12BIAX,app.G13UD,app.G13BIAX,app.G23UD,app.G23BIAX,app.first_Step,app.Tperiod,app.Min_incr,app.Max_incr,app.Viscosity,app.E1UD_EXTRA,app.E1BIAX_EXTRA,app.E2UD_EXTRA,app.E2BIAX_EXTRA,app.E3UD_EXTRA,app.E3BIAX_EXTRA,app.V12UD_EXTRA,app.V12BIAX_EXTRA,app.V13UD_EXTRA,app.V13BIAX_EXTRA,app.V23UD_EXTRA,app.V23BIAX_EXTRA,app.G12UD_EXTRA,app.G12BIAX_EXTRA,app.G13UD_EXTRA,app.G13BIAX_EXTRA,app.G23UD_EXTRA,app.G23BIAX_EXTRA,app.Daminit_UD,app.Daminit_UD_DIFF,app.Daminit_BIAX,app.Daminit_BIAX_DIFF,app.Daminit_Resin1,app.Daminit_Resin2,app.Daminit_Resin3,app.Damev_UD,app.Damev_BIAX,app.Damev_Resin,app.Frac_UD1,app.Frac_UD2,app.Frac_UD3,app.Frac_BIAX1,app.Frac_BIAX2,app.Frac_BIAX3,app.Frac_Resin1,app.Frac_Resin2,app.Frac_Resin3,app.Frac_UD1_DIFF,app.Frac_UD2_DIFF,app.Frac_UD3_DIFF,app.Frac_BIAX1_DIFF,app.Frac_BIAX2_DIFF,app.Frac_BIAX3_DIFF,app.Knn,app.Kss,app.Ktt,app.Damev_UD_DIFF,app.Damev_BIAX_DIFF); % Resin modelled with cohesive elements
                        else if (resinEl == 0)
                                K_abaqusmatdiffSolid_function(app.flag, app.layup,app.t_Hres,app.t_overmat,app.E1UD,app.E1BIAX,app.E2UD,app.E2BIAX,app.E3UD,app.E3BIAX,app.V12UD,app.V12BIAX,app.V13UD,app.V13BIAX,app.V23UD,app.V23BIAX,app.G12UD,app.G12BIAX,app.G13UD,app.G13BIAX,app.G23UD,app.G23BIAX,app.first_Step,app.Tperiod,app.Min_incr,app.Max_incr,app.Viscosity,app.E1RESIN,app.V12RESIN,app.E1UD_EXTRA,app.E1BIAX_EXTRA,app.E2UD_EXTRA,app.E2BIAX_EXTRA,app.E3UD_EXTRA,app.E3BIAX_EXTRA,app.V12UD_EXTRA,app.V12BIAX_EXTRA,app.V13UD_EXTRA,app.V13BIAX_EXTRA,app.V23UD_EXTRA,app.V23BIAX_EXTRA,app.G12UD_EXTRA,app.G12BIAX_EXTRA,app.G13UD_EXTRA,app.G13BIAX_EXTRA,app.G23UD_EXTRA,app.G23BIAX_EXTRA); % Resin modelled with solid elements
                        end
                        end
                    else if (Loading == 1) % Bending case
                            if (resinEl == 1)
                                L_abaqusmatdiffCZM_function(app.V12RESIN,app.E1RESIN,app.I_o,app.I_R,app.Damstab,app.t_Vres,app.flag, app.layup,app.t_Hres,app.t_overmat,app.E1UD,app.E1BIAX,app.E2UD,app.E2BIAX,app.E3UD,app.E3BIAX,app.V12UD,app.V12BIAX,app.V13UD,app.V13BIAX,app.V23UD,app.V23BIAX,app.G12UD,app.G12BIAX,app.G13UD,app.G13BIAX,app.G23UD,app.G23BIAX,app.first_Step,app.Tperiod,app.Min_incr,app.Max_incr,app.Viscosity,app.E1UD_EXTRA,app.E1BIAX_EXTRA,app.E2UD_EXTRA,app.E2BIAX_EXTRA,app.E3UD_EXTRA,app.E3BIAX_EXTRA,app.V12UD_EXTRA,app.V12BIAX_EXTRA,app.V13UD_EXTRA,app.V13BIAX_EXTRA,app.V23UD_EXTRA,app.V23BIAX_EXTRA,app.G12UD_EXTRA,app.G12BIAX_EXTRA,app.G13UD_EXTRA,app.G13BIAX_EXTRA,app.G23UD_EXTRA,app.G23BIAX_EXTRA,app.Daminit_UD,app.Daminit_UD_DIFF,app.Daminit_BIAX,app.Daminit_BIAX_DIFF,app.Daminit_Resin1,app.Daminit_Resin2,app.Daminit_Resin3,app.Damev_UD,app.Damev_BIAX,app.Damev_Resin,app.Frac_UD1,app.Frac_UD2,app.Frac_UD3,app.Frac_BIAX1,app.Frac_BIAX2,app.Frac_BIAX3,app.Frac_Resin1,app.Frac_Resin2,app.Frac_Resin3,app.Frac_UD1_DIFF,app.Frac_UD2_DIFF,app.Frac_UD3_DIFF,app.Frac_BIAX1_DIFF,app.Frac_BIAX2_DIFF,app.Frac_BIAX3_DIFF,app.Knn,app.Kss,app.Ktt,app.Damev_UD_DIFF,app.Damev_BIAX_DIFF); % Resin modelled with cohesive elements
                            else if (resinEl == 0)
                                    L_abaqusmatdiffSolid_function(app.flag, app.layup,app.t_Hres,app.t_overmat,app.E1UD,app.E1BIAX,app.E2UD,app.E2BIAX,app.E3UD,app.E3BIAX,app.V12UD,app.V12BIAX,app.V13UD,app.V13BIAX,app.V23UD,app.V23BIAX,app.G12UD,app.G12BIAX,app.G13UD,app.G13BIAX,app.G23UD,app.G23BIAX,app.first_Step,app.Tperiod,app.Min_incr,app.Max_incr,app.Viscosity,app.E1RESIN,app.V12RESIN,app.E1UD_EXTRA,app.E1BIAX_EXTRA,app.E2UD_EXTRA,app.E2BIAX_EXTRA,app.E3UD_EXTRA,app.E3BIAX_EXTRA,app.V12UD_EXTRA,app.V12BIAX_EXTRA,app.V13UD_EXTRA,app.V13BIAX_EXTRA,app.V23UD_EXTRA,app.V23BIAX_EXTRA,app.G12UD_EXTRA,app.G12BIAX_EXTRA,app.G13UD_EXTRA,app.G13BIAX_EXTRA,app.G23UD_EXTRA,app.G23BIAX_EXTRA); % Resin modelled with solid elements
                            end
                            end
                    end
                    end
            end
            end

            %% Until here the code can be run with MATLAB, after this moment a full ABAQUS license it's requiered.
            % Write the necessary lines in .inp so that ABAQUS gives the dessired ouput

            func_replace_string2(strcat(namefile,namefileinp), strcat(namefile,namefileinp), '*End Step', '*NODE PRINT, nset=RP_RIGHT, frequency=1')
            fid = fopen(strcat(namefile,namefileinp),'a');
            if Loading == 0
                fprintf(fid,'RF1,RF2,RM3,U1\r\n');
            elseif Loading == 1
                fprintf(fid,'RF1,RF2,RM3,U1\r\n');
            end
            %1st element case
            if app.flag == 0 || app.flag == 1
                fprintf(fid,'*EL PRINT, elset=REPAIREDPANEL-1.EINTERFACE, frequency=1\r\n');
                fprintf(fid,'S11,S22,S33,S12,LE11,LE22,LE33,LE12\r\n');
                %2nd element case
                if over_lam == 1
                    fprintf(fid,'*EL PRINT, elset=REPAIREDPANEL-1.EOVERLAM, frequency=1\r\n');
                    fprintf(fid,'S11,S22,S33,S12,LE11,LE22,LE33,LE12\r\n');
                end
                %3rd element case
                if (app.flag == 0) || (app.flag == 1 && contains(layup_defect_char{1,1},'biax')==1)
                    fprintf(fid,'*EL PRINT, elset=REPAIREDPANEL-1.EPARENTBIAX, frequency=1\r\n');
                    fprintf(fid,'S11,S22,S33,S12,LE11,LE22,LE33,LE12\r\n');
                end
                %4th element case
                if (app.flag == 0) || (app.flag == 1 && contains(layup_defect_char{1,1},'ud')==1)
                    fprintf(fid,'*EL PRINT, elset=REPAIREDPANEL-1.EPARENTUD, frequency=1\r\n');
                    fprintf(fid,'S11,S22,S33,S12,LE11,LE22,LE33,LE12\r\n');
                end
                %5th element case
                if (mat_change == 1) && (app.flag == 0 || (app.flag == 1 && contains(layup_defect_char{1,1},'biax')==1))
                    fprintf(fid,'*EL PRINT, elset=REPAIREDPANEL-1.EREPAIRBIAX1, frequency=1\r\n');
                    fprintf(fid,'S11,S22,S33,S12,LE11,LE22,LE33,LE12\r\n');
                end
                %6st element case
                if (mat_change == 1) && (app.flag == 0 || (app.flag == 1 && contains(layup_defect_char{1,1},'ud')==1))
                    fprintf(fid,'*EL PRINT, elset=REPAIREDPANEL-1.EREPAIRUD1, frequency=1\r\n');
                    fprintf(fid,'S11,S22,S33,S12,LE11,LE22,LE33,LE12\r\n');
                end
                %7 element case
                fprintf(fid,'*EL PRINT, elset=REPAIREDPANEL-1.ERESIN, frequency=1\r\n');
                fprintf(fid,'S11,S22,S33,S12,LE11,LE22,LE33,LE12\r\n');
                %8st element case
                if (mat_change==1)
                    fprintf(fid,'*EL PRINT, elset=REPAIREDPANEL-1.ERESINRICH, frequency=1\r\n');
                    fprintf(fid,'S11,S22,S33,S12,LE11,LE22,LE33,LE12\r\n');
                end
                %9th element case
                if (mat_change == 0) && (app.flag == 0 || (app.flag == 1 && contains(layup_defect_char{1,1},'biax')==1))
                    fprintf(fid,'*EL PRINT, elset=REPAIREDPANEL-1.EREPAIRBIAX, frequency=1\r\n');
                    fprintf(fid,'S11,S22,S33,S12,LE11,LE22,LE33,LE12\r\n');
                end
                %10th element case
                if (mat_change == 0) && (app.flag == 0 || (app.flag == 1 && contains(layup_defect_char{1,1},'ud')==1))
                    fprintf(fid,'*EL PRINT, elset=REPAIREDPANEL-1.EREPAIRUD, frequency=1\r\n');
                    fprintf(fid,'S11,S22,S33,S12,LE11,LE22,LE33,LE12\r\n');
                end
            elseif app.flag == 2
                fprintf(fid,'*EL PRINT, elset=REPAIREDPANEL-1.ECOMPOSITEBIAX, frequency=1\r\n');
                fprintf(fid,'S11,S22,S33,S12,LE11,LE22,LE33,LE12\r\n');
                fprintf(fid,'*EL PRINT, elset=REPAIREDPANEL-1.ECOMPOSITEUD, frequency=1\r\n');
                fprintf(fid,'S11,S22,S33,S12,LE11,LE22,LE33,LE12\r\n');
                fprintf(fid,'*EL PRINT, elset=REPAIREDPANEL-1.EINTERFACE, frequency=1\r\n');
                fprintf(fid,'S11,S22,S33,S12,LE11,LE22,LE33,LE12\r\n');
            elseif app.flag == 3 && contains(layup_defect_char{1,1},'ud')==1
                fprintf(fid,'*EL PRINT, elset=REPAIREDPANEL-1.ECOMPOSITEUD, frequency=1\r\n');
                fprintf(fid,'S11,S22,S33,S12,LE11,LE22,LE33,LE12\r\n');
                fprintf(fid,'*EL PRINT, elset=REPAIREDPANEL-1.EINTERFACE, frequency=1\r\n');
                fprintf(fid,'S11,S22,S33,S12,LE11,LE22,LE33,LE12\r\n');
            elseif app.flag == 3 && contains(layup_defect_char{1,1},'biax')==1
                fprintf(fid,'*EL PRINT, elset=REPAIREDPANEL-1.ECOMPOSITEBIAX, frequency=1\r\n');
                fprintf(fid,'S11,S22,S33,S12,LE11,LE22,LE33,LE12\r\n');
                fprintf(fid,'*EL PRINT, elset=REPAIREDPANEL-1.EINTERFACE, frequency=1\r\n');
                fprintf(fid,'S11,S22,S33,S12,LE11,LE22,LE33,LE12\r\n');
            end
            %fprintf(fid, '*PRINT, CONTACT=YES\r\n');
            fprintf(fid, '*PRINT, PLASTICITY=YES\r\n');
            fprintf(fid, '*End Step\r\n');
            fclose(fid);
            % Change the JOB state
            app.JOBEditField.Value = 'Running ABAQUS';
            % n is the number of plys. It is needed to read the output_ele
            % function correctly.
            % n1 = size(app.y_store);
            % n = n1(1);
            %% Send the .inp file to ABAQUS, submit and read the output
            if Loading == 0
                [app.RF1,RF2,RM3,app.U1] = Run_job_request_outputs_function(namefile,N_cores);
                %[RF1,RF2] = Run_job_request_outputs_function(namefile);


            elseif Loading == 1
                [app.RF1,RF2,RM3,app.U1] = Run_job_request_outputs_function(namefile,N_cores);
                %[RM3,RF2] = Run_job_request_outputs_function(namefile);

            end
            % All the values area negative so abs command is needed to
            % get the maximum value
            if abs(min(app.RF1))>max(app.RF1)
                app.RF1NEditField.Value = min(app.RF1);
            else
                app.RF1NEditField.Value = max (app.RF1);
            end
            if abs(min(RF2))>max(RF2)
                app.RF2NEditField.Value = min(RF2);
            else
                app.RF2NEditField.Value = max (RF2);
            end
            if abs(min(RM3))>max(RM3)
                app.RM3NmEditField.Value = min(RM3);
            else
                app.RM3NmEditField.Value = max (RM3);
            end

            %% Calculate the Young's modulus or the Bending stiffness
            if Loading == 0
                E = Youngs_modulus(x_disp,app.RF1(end),Tot_length/1000,Tot_thick/1000);

                app.EGpaEditField.Value = E;
            elseif Loading == 1
                D = Bending_stiffness(theta_rot,RM3(end),Tot_length/1000);

                app.DNmEditField.Value = D;
            end
            % Create a table with the data and variable names
            T1 = table(app.RF1', app.U1','VariableNames', { '     RF1     ', '     U1     '} );
            T2 = table(Tot_length,Tot_thick,E,'VariableNames',{'     Length     ','     Thickness   ','     E     '});
            % Write data to text file
            writetable(T1, 'RF1_U1_60_3.txt');
            writetable(T2, 'Length_Thickness_E_3.txt');
            %app.S11ResinEditfield.Value = max(S11);
            %ppal_LE11 = LE11;
            % Change the JOB state
            app.JOBEditField.Value = 'Done';
            app.TimesEditField.Value = toc;

        end

        % Callback function: ChooseplotDropDown, JOBEditField
        function ChooseplotDropDownValueChanged(app, event)
            %% Plots

            plot_decision_str = app.ChooseplotDropDown.Value;
            if strcmp(plot_decision_str,'1. Wrinkle interpolation curve')
                % 1st plot
                if app.flag == 0 || app.flag == 1 
                plot_decision = 1;
                end
            end
            if strcmp(plot_decision_str,'2. Wrinkled unhealthy layers')
                if app.flag == 0 || app.flag == 1 
                plot_decision = 2;
                end
            end
            if strcmp(plot_decision_str,'3. Laminate with defect')
                if app.flag == 0 || app.flag == 1 
                plot_decision = 3;
                end
            end
            if strcmp(plot_decision_str,'4. Detect defect')
                if app.flag == 0 || app.flag == 1 
                plot_decision = 4;
                end
            end
            if strcmp(plot_decision_str,'5. Remove defect')
               if app.flag == 0 || app.flag == 1 
                plot_decision = 5;
               end
            end
            if strcmp(plot_decision_str,'6. Replace damaged layers')
                if app.flag == 0 || app.flag == 1 
                plot_decision = 6;
                end
            end
            if strcmp(plot_decision_str,'7. Element grid size')
                if app.flag == 0 || app.flag == 1 
                plot_decision = 7;
                end
            end
            if strcmp(plot_decision_str,'8. Load vs Displacement')
                if app.flag == 0 || app.flag == 1 
                plot_decision = 8;
                end
            end

            switch plot_decision
                case 1
                    
                    legend(app.UIAxes,'off');
                    %figure('Renderer', 'painters', 'Position', [10 10 900 600])
                    plot(app.UIAxes,app.x,app.yhat,'.r','LineWidth',1,'MarkerSize',11);
                    title(app.UIAxes,'Wrinkle interpolation curve');
                    xlabel(app.UIAxes,'length / mm','Interpreter','latex','Fontsize',13);
                    ylabel(app.UIAxes,'thickness / mm','Interpreter','latex','Fontsize',13);
                    %set(app.UIAxes,'position',[x0,y0,width,height])
                    xlim(app.UIAxes,sort([app.x(1) app.x(end)]));
                    legend(app.UIAxes,'Wrinkle');


                case 2

                    legend(app.UIAxes,'off');
                    plot(app.UIAxes,app.x_store(1,:),app.y_store,'-k','LineWidth',1,'MarkerSize',11);
                    title(app.UIAxes,'Wrinkled unhealthy layers');
                    xlabel(app.UIAxes,'length / mm','Interpreter','latex','Fontsize',13);
                    ylabel(app.UIAxes,'thickness / mm','Interpreter','latex','Fontsize',13);
                    %set(gcf,'position',[x0,y0,width,height])
                    xlim(app.UIAxes,sort([app.x(1) app.x(end)]));
                    legend(app.UIAxes,'Wrinkled layers');


                case 3

                    legend(app.UIAxes,'off');
                    if(app.flag == 0) || (app.flag == 2)
                        idBiax   = find(app.layup == app.biax);
                        idUd     = find(app.layup == app.ud);
                        plotbiax = app.y_aseb(idBiax,:);
                        plotud   = app.y_aseb(idUd,:);
                        %hold (app.UIAxes,'on');
                        %plot(app.UIAxes,app.x_aseb(1, :)/1000, plotbiax/1000, '.r');
                        plot(app.UIAxes,app.x_aseb(1, :), plotbiax(1,:), '.r','DisplayName','BIAX');
                        hold (app.UIAxes,'on');
                        plot(app.UIAxes,app.x_aseb(1, :), plotud(1,:), '.b','DisplayName','UD');
                        legend(app.UIAxes,'AutoUpdate','off');
                        hold (app.UIAxes,'on');
                        plot(app.UIAxes,app.x_aseb(1, :), plotbiax(2:end,:), '.r');
                        hold (app.UIAxes,'on');
                        %plot(app.UIAxes,app.x_aseb(1, :)/1000, plotud/1000, '.b');                        hold (app.UIAxes,'on');
                        plot(app.UIAxes,app.x_aseb(1, :), plotud(2:end,:), '.b');
                        title(app.UIAxes,' Laminate with defect');
                        xlabel(app.UIAxes,'length / mm','Interpreter','latex','Fontsize',13);
                        ylabel(app.UIAxes,'thickness / mm','Interpreter','latex','Fontsize',13);
                        %xlim(app.UIAxes,sort([app.x_aseb(1)/1000 app.x_aseb(end)/1000]));
                        xlim(app.UIAxes,sort([app.x_aseb(1) app.x_aseb(end)]));
                        %set(gcf,'position',[x0,y0,width,height])
                        hold (app.UIAxes,'off');
                    else if (app.flag == 1) || (app.flag == 3)
                            if app.layup(1,1) == app.biax
                                idBiax   = find(app.layup == app.biax);
                                plotbiax = app.y_aseb(idBiax,:);
                                %(app.UIAxes,app.x_aseb(1, :)/1000, plotbiax/1000, '.r');
                                plot(app.UIAxes,app.x_aseb(1,:) , plotbiax, '.r');
                                hold (app.UIAxes,'on');
                                legend(app.UIAxes,'BIAX');
                                title(app.UIAxes,' Laminate with defect');
                                xlabel(app.UIAxes,'length / mm','Interpreter','latex','Fontsize',13);
                                ylabel(app.UIAxes,'thickness / mm','Interpreter','latex','Fontsize',13);
                                %set(gcf,'position',[x0,y0,width,height]);
                                %xlim(app.UIAxes,sort([app.x_aseb(1)/1000 app.x_aseb(end)/1000]));
                                xlim(app.UIAxes,sort([app.x_aseb(1) app.x_aseb(end)]));
                                hold (app.UIAxes,'off');
                            else if app.layup(1,1) == app.ud
                                    idUd     = find(app.layup == app.ud);
                                    plotud   = app.y_aseb(idUd,:);
                                    %plot(app.UIAxes,app.x_aseb(1, :)/1000, plotud/1000, '.b');
                                    plot(app.UIAxes,app.x_aseb(1, :), plotud, '.b');
                                    hold (app.UIAxes,'on');
                                    legend(app.UIAxes,'UD');
                                    title(app.UIAxes,' Laminate with defect');
                                    xlabel(app.UIAxes,'length / mm','Interpreter','latex','Fontsize',13);
                                    ylabel(app.UIAxes,'thickness / mm','Interpreter','latex','Fontsize',13);
                                    %xlim(app.UIAxes,sort([app.x_aseb(1)/1000 app.x_aseb(end)/1000]));
                                    xlim(app.UIAxes,sort([app.x_aseb(1) app.x_aseb(end)]));
                                    hold (app.UIAxes,'off');
                                    %set(gcf,'position',[x0,y0,width,height])
                            end
                            end
                    end
                    end

            end

            if (app.flag==0) || (app.flag==1)
                if plot_decision == 4

                    legend(app.UIAxes,'off');
                    %plot(app.UIAxes,app.x_hty(1, :)/1000, app.y_hty/1000, '.k'); % healthy layers below
                    %                     % Find area coordinates.
                    %                     inBetweenRegionX = [1:length(app.xrep_right_aseb{1,1}(:,:)), length(app.x_right_par{1,1}(:,:)):-1:1];
                    %                     inBetweenRegionY = [app.xrep_right_aseb{1,1}(:,:), fliplr(app.x_right_par{1,1}(:,:))];
                    %                     % Display the area first so it will be in the background.
                    %                     fill(app.UIAxes,inBetweenRegionX, inBetweenRegionY, 'g');
                    %                     hold on;
                    plot(app.UIAxes,app.x_hty(1, :), app.y_hty, '.k');

                    hold (app.UIAxes,'on');
                    for i10 = 1:length(app.L_remrow)
                        %hold (app.UIAxes,'on');
                        %plot(app.UIAxes,app.xrep_right_aseb{i10,1}(:,:)/1000, app.yrep_right_aseb{i10,1}(:,:)/1000, '.r'); % repair layers of left
                        plot(app.UIAxes,app.xrep_right_aseb{i10,1}(:,:), app.yrep_right_aseb{i10,1}(:,:),'.r'); % repair layers of left
                        hold (app.UIAxes,'on');
                        %plot(app.UIAxes,app.xrep_left_aseb{i10,1}(:,:)/1000, app.yrep_left_aseb{i10,1}(:,:)/1000, '.r'); % repair layers of right
                        plot(app.UIAxes,app.xrep_left_aseb{i10,1}(:,:), app.yrep_left_aseb{i10,1}(:,:), '.r'); % repair layers of right
                        hold (app.UIAxes,'on');
                        %plot(app.UIAxes,app.x_right_par{i10,1}(:,:)/1000, app.y_right_par{i10,1}(:,:)/1000, '.k'); % repair layers of left
                        plot(app.UIAxes,app.x_right_par{i10,1}(:,:), app.y_right_par{i10,1}(:,:), '.k'); % repair layers of left
                        hold (app.UIAxes,'on');
                        %plot(app.UIAxes,app.x_left_par{i10,1}(:,:)/1000, app.y_left_par{i10,1}(:,:)/1000, '.k'); % repair layers of right
                        plot(app.UIAxes,app.x_left_par{i10,1}(:,:), app.y_left_par{i10,1}(:,:), '.k'); % repair layers of right
                        title(app.UIAxes,'Detect defect');
                        xlabel(app.UIAxes,'length / mm','Interpreter','latex','Fontsize',13);
                        ylabel(app.UIAxes,'thickness / mm','Interpreter','latex','Fontsize',13);
                        %set(gcf,'position',[x0,y0,width,height])
                        %xlim(app.UIAxes,sort([app.x_aseb(1)/1000 app.x_aseb(end)/1000]));
                        xlim(app.UIAxes,sort([app.x_aseb(1) app.x_aseb(end)]));
                        hold (app.UIAxes,'on');

                    end

                    hold (app.UIAxes,'off');

                end

                if plot_decision == 5

                    legend(app.UIAxes,'off');
                    %plot(app.UIAxes,app.x_hty(1, :)/1000, app.y_hty/1000, '.k'); % healthy layers below
                    plot(app.UIAxes,app.x_hty(1, :), app.y_hty, '.k'); % healthy layers below
                    hold (app.UIAxes,'on');
                    for i11 = 1:length(app.L_remrow)
                        %plot(app.UIAxes,app.x_right_par{i11,1}(:,:)/1000, app.y_right_par{i11,1}(:,:)/1000, '.k'); % parent layers of left
                        plot(app.UIAxes,app.x_right_par{i11,1}(:,:), app.y_right_par{i11,1}(:,:), '.k'); % parent layers of left
                        hold (app.UIAxes,'on');
                        %plot(app.UIAxes,app.x_left_par{i11,1}(:,:)/1000, app.y_left_par{i11,1}(:,:)/1000, '.k'); % parent layers of right
                        plot(app.UIAxes,app.x_left_par{i11,1}(:,:), app.y_left_par{i11,1}(:,:), '.k'); % parent layers of right
                        title(app.UIAxes,'Remove defect');
                        xlabel(app.UIAxes,'length / mm','Interpreter','latex','Fontsize',13);
                        ylabel(app.UIAxes,'thickness / mm','Interpreter','latex','Fontsize',13);
                        %xlim(app.UIAxes,sort([app.x_aseb(1)/1000 app.x_aseb(end)/1000]));
                        xlim(app.UIAxes,sort([app.x_aseb(1) app.x_aseb(end)]));
                        %set(gcf,'position',[x0,y0,width,height])
                        hold (app.UIAxes,'on');

                    end
                    hold (app.UIAxes,'off');
                end
                if plot_decision == 6

                    legend(app.UIAxes,'off');
                    %plot(app.UIAxes,app.x_hty(1, :)/1000, app.y_hty/1000, '.k'); % healthy layers below
                    plot(app.UIAxes,app.x_hty(1, :), app.y_hty, '.k'); % healthy layers below
                    hold (app.UIAxes,'on');
                    for i11 = 1:length(app.L_remrow)
                        %plot(app.UIAxes,app.x_right_par{i11,1}(:,:)/1000, app.y_right_par{i11,1}(:,:)/1000, '.k'); % parent layers of left
                        plot(app.UIAxes,app.x_right_par{i11,1}(:,:), app.y_right_par{i11,1}(:,:), '.k'); % parent layers of left
                        hold (app.UIAxes,'on');
                        %plot(app.UIAxes,app.x_left_par{i11,1}(:,:)/1000, app.y_left_par{i11,1}(:,:)/1000, '.k'); % parent layers of right
                        plot(app.UIAxes,app.x_left_par{i11,1}(:,:), app.y_left_par{i11,1}(:,:), '.k'); % parent layers of right
                        hold (app.UIAxes,'on');
                        %plot(app.UIAxes,app.x_rep{i11,1}(:,:)/1000, app.y_rep{i11,1}(:,:)/1000, 'Color',[0.4660 0.6740 0.1880]); % repair layers
                        plot(app.UIAxes,app.x_rep{i11,1}(:,:), app.y_rep{i11,1}(:,:), 'Color',[0.4660 0.6740 0.1880]); % repair layers
                        title(app.UIAxes,'Replace damaged layers');
                        xlabel(app.UIAxes,'length / mm','Interpreter','latex','Fontsize',13);
                        ylabel(app.UIAxes,'thickness / mm','Interpreter','latex','Fontsize',13);
                        %xlim(app.UIAxes,sort([app.x_aseb(1)/1000 app.x_aseb(end)/1000]));
                        xlim(app.UIAxes,sort([app.x_aseb(1) app.x_aseb(end)]));
                        %set(gcf,'position',[x0,y0,width,height])
                        hold (app.UIAxes,'on');

                    end
                    hold (app.UIAxes,'off');
                end
            end

            if plot_decision == 7

                legend(app.UIAxes,'off');
                %plot(app.UIAxes,app.X_res_Tot(1, :)/1000, app.yRes/1000, 'Color',[0.9290 0.6940 0.1250], 'LineWidth',3,'MarkerSize',11);
                plot(app.UIAxes,app.X_res_Tot(1, :), app.yRes, 'Color',[0.9290 0.6940 0.1250], 'LineWidth',3,'MarkerSize',11);
                hold (app.UIAxes,'on');
                %plot(app.UIAxes,app.X_res_Tot(1, :)/1000, app.Y_res_Tot/1000, '.k', 'LineWidth',3,'MarkerSize',11);
                plot(app.UIAxes,app.X_res_Tot(1, :), app.Y_res_Tot, '.k', 'LineWidth',3,'MarkerSize',11);
                title(app.UIAxes,'Element grid size');
                % leyend??
                xlabel(app.UIAxes,'length / mm','Interpreter','latex','Fontsize',13);
                ylabel(app.UIAxes,'thickness / mm','Interpreter','latex','Fontsize',13);
                %xlim(app.UIAxes,sort([app.X_res_Tot(1)/1000 app.X_res_Tot(end)/1000]));
                xlim(app.UIAxes,sort([app.X_res_Tot(1) app.X_res_Tot(end)]));
                hold (app.UIAxes,'off');
                %set(gcf,'position',[x0,y0,width,height])

            end
            if plot_decision == 8
                plot_function(app.U1,app.TotallengthofthecompositemmEditField.Value,app.RF1,app.TotalthicknessmmEditField.Value);
%                 legend(app.UIAxes,'off');
%                 plot(app.UIAxes,app.U1*1000,app.RF1/(app.TotalthicknessmmEditField.Value));
%                 title(app.UIAxes, 'Deformation vs Stress');
%                 xlabel(app.UIAxes,'Deformation (mm)');
%                 ylabel(app.UIAxes,'Stress (kN/mm)');
            end

        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            app.JOBEditField.Value = '';
            app.TimesEditField.Value = 0;
            app.EGpaEditField.Value = 0;
            app.DNmEditField.Value = 0;
            app.TotalthicknessmmEditField.Value = 0;
            app.TotallengthofthecompositemmEditField.Value = 0;
            app.TotallengthoftherepairmmEditField.Value = 0;
            cla(app.UIAxes);

        end

        % Value changed function: ChooseelementtypeDropDown
        function ChooseelementtypeDropDownValueChanged(app, event)
            % name of the output file
            namefile = app.FilenameEditField.Value;
            %% Flag options
            flag_option = app.FlagDropDown.Value;
            % Convert the option into a value into flag value
            if strcmp(flag_option,'0: Composite with defect and layup contains two different lamina materials')
                app.flag = 0;

            elseif strcmp(flag_option,'1: Composite with defect and layup contains one type of lamina material')
                app.flag = 1;
            elseif strcmp(flag_option,'2: Composite without defect and layup contains two different lamina materials')
                app.flag = 2;
            elseif strcmp(flag_option,'3: Composite without defect and layup contains one type of lamina material')
                app.flag = 3;
            end
            % Overlamina addtion
            over_lam_gui = app.OverlaminaDropDown.Value;
            if strcmp(over_lam_gui,'Yes')
                over_lam = 1;
            else
                over_lam = 0;
            end
            % Ply by ply match
            mat_change_option = app.PlymatchDropDown.Value;  % 0: ply by ply match, 1: ply by ply mismatch
            % Convert the option into a value into resinEl value
            if strcmp(mat_change_option,'0: ply by ply match')
                mat_change = 0;
            elseif strcmp(mat_change_option,'1: ply by ply mismatch')
                mat_change = 1;
            end
            % Layupdefect
            layup_defect_notconverted = app.LayupwithdefectegudbiaxudEditField.Value;
            layup_defect_char = (strsplit(layup_defect_notconverted,','));
            % Layup nodefect
            layup_nodefect_notconverted = app.LayupwithoutdefectegudbiaxudEditField.Value;
            layup_nodefect_char = (strsplit(layup_nodefect_notconverted,','));

            %% Resin Element
            resinEl_option = app.TypeofelementDropDown.Value;
            % Convert the option into a value into resinEl value
            if strcmp(resinEl_option,'0: Homogeneous elements')
                resinEl = 0;
            elseif strcmp(resinEl_option,'1: Cohesive elements')
                resinEl = 1;
            end

            % Check the dropdown to know which element type ouput to show.
            output_decision_str = app.ChooseelementtypeDropDown.Value;

            if strcmp(output_decision_str,'1. EINTERFACE')
                % 1st stress output
                if resinEl == 0
                    output_decision = 1;
                else
                    app.JOBEditField_2.Value = 'S11, S33, LE11 and LE33 are not applicable for cohesive elements';
                    output_decision = 12;
                end
            end
            if strcmp(output_decision_str,'2. EOVERLAM')
                if (app.flag == 0 || app.flag == 1)
                    if (over_lam == 1)
                        output_decision = 2;
                    else
                        app.JOBEditField_2.Value = 'Overlamina option is not selected';
                        output_decision = 11;
                    end
                else
                    app.JOBEditField_2.Value = 'Overlamina option is not applicable for pristine laminates';
                    output_decision = 11;
                end
            end
            if strcmp(output_decision_str,'3. EPARENTBIAX')
                if (app.flag == 0 || app.flag == 1)
                    if ((app.flag == 1 && (contains(layup_nodefect_char{1,1},'biax')==1)) || app.flag == 0)
                        output_decision = 3;
                    else
                        app.JOBEditField_2.Value = 'The parent laminate does not have BIAX material';
                        output_decision = 11;
                    end
                else
                    app.JOBEditField_2.Value = 'Parent BIAX option is not applicable for pristine laminates. Select ECOMPOSITEBIAX';
                    output_decision = 11;
                end
            end
            if strcmp(output_decision_str,'4. EPARENTUD')
                if (app.flag == 0 || app.flag == 1)
                    if ((app.flag == 1 && (contains(layup_nodefect_char{1,1},'ud')==1)) || app.flag == 0)
                        output_decision = 4;
                    else
                        app.JOBEditField_2.Value = 'The parent laminate does not have UD material';
                        output_decision = 11;
                    end
                else
                    app.JOBEditField_2.Value = 'Parent UD option is not applicable for pristine laminates. Select ECOMPOSITEUD';
                    output_decision = 11;
                end
            end
            if strcmp(output_decision_str,'5. EREPAIRBIAX1')
                if (app.flag == 0 || app.flag == 1)
                    if (mat_change == 1) && (app.flag == 0 || (app.flag == 1 && (contains(layup_defect_char{1,1},'biax')==1)))
                        output_decision = 5;
                    else
                        if (mat_change == 0)
                            app.JOBEditField_2.Value = 'Mismatch option is not selected.';
                        end
                        if (mat_change == 1) && ((contains(layup_defect_char{1,1},'biax')==0))
                            app.JOBEditField_2.Value = 'The repair does not have BIAX material';
                        end
                        output_decision = 11;
                    end
                else
                    app.JOBEditField_2.Value = 'Repair BIAX option is not applicable for pristine laminates. Select ECOMPOSITEBIAX';
                    output_decision = 11;
                end
            end
            if strcmp(output_decision_str,'6. EREPAIRUD1')
                if (app.flag == 0 || app.flag == 1)
                    if (mat_change == 1) && (app.flag == 0 || (app.flag == 1 && (contains(layup_defect_char{1,1},'ud')==1)))
                        output_decision = 6;
                    else
                        if (mat_change == 0)
                            app.JOBEditField_2.Value = 'Mismatch option is not selected.';
                        end
                        if (mat_change == 1) && ((contains(layup_defect_char{1,1},'ud')==0))
                            app.JOBEditField_2.Value = 'The repair does not have UD material';
                        end
                        output_decision = 11;
                    end
                else
                    app.JOBEditField_2.Value = 'Repair UD option is not applicable for pristine laminates. Select ECOMPOSITEUD';
                    output_decision = 11;
                end
            end
            if strcmp(output_decision_str,'7. ERESIN')
                if (app.flag == 0 || app.flag == 1)
                    if resinEl == 0
                        output_decision = 7;
                    else
                        app.JOBEditField_2.Value = 'S11, S33, LE11 and LE33 are not applicable for COH2D4';
                        output_decision = 13;
                    end
                else
                    app.JOBEditField_2.Value = 'Resin option is not applicable for pristine laminates';
                    output_decision = 11;
                end
            end
            if strcmp(output_decision_str,'8. RESINRICH')
                if (app.flag == 0 || app.flag == 1)
                    if (mat_change == 1) && (resinEl == 0)
                        output_decision = 8;
                    else
                        if mat_change == 0 && resinEl == 0
                            app.JOBEditField_2.Value = 'There is no resin pocket. Mismatch option is not selected';
                            output_decision = 11;
                        end
                        if resinEl == 1 && mat_change == 1
                            app.JOBEditField_2.Value = 'S11, S33, LE11 and LE33 are not applicable for COH2D4';
                            output_decision = 14;
                        end
                    end
                else
                    app.JOBEditField_2.Value = 'There is no resin pocket in pristine laminates. Mismatch option is not applicable';
                    output_decision = 11;
                end
            end
            if strcmp(output_decision_str,'9. EREPAIRBIAX')
                if (app.flag == 0 || app.flag == 1)
                    if (mat_change == 0) && (app.flag == 0 || (app.flag == 1 && (contains(layup_defect_char{1,1},'biax')==1)))
                        output_decision = 9;
                    else
                        app.JOBEditField_2.Value = 'Mismatch option is selected.';
                        output_decision = 11;
                    end
                else
                    app.JOBEditField_2.Value = 'Repair BIAX option is not applicable for pristine laminates. Select ECOMPOSITEBIAX';
                    output_decision = 11;
                end
            end
            if strcmp(output_decision_str,'10. EREPAIRUD')
                if (app.flag == 0 || app.flag == 1)
                    if (mat_change == 0) && (app.flag == 0 || (app.flag == 1 && (contains(layup_defect_char{1,1},'ud')==1)))
                        output_decision = 10;
                    else
                        app.JOBEditField_2.Value = 'Mismatch option is selected.';
                        output_decision = 11;
                    end
                else
                    app.JOBEditField_2.Value = 'Repair UD option is not applicable for pristine laminates. Select ECOMPOSITEUD';
                    output_decision = 11;
                end
            end
            if strcmp(output_decision_str,'11. ECOMPOSITEBIAX')
                if (app.flag == 0 || app.flag == 1)
                    app.JOBEditField_2.Value = 'Composite BIAX option is not applicable for repaired laminates';
                    output_decision = 11;
                elseif app.flag == 2 || (app.flag == 3 && (contains(layup_defect_char{1,1},'biax')==1))
                    output_decision = 15;
                else
                    app.JOBEditField_2.Value = 'There is no BIAX material in the pristine model';
                    output_decision = 11;
                end
            end

            if strcmp(output_decision_str,'12. ECOMPOSITEUD')
                if (app.flag == 0 || app.flag == 1)
                    app.JOBEditField_2.Value = 'Composite UD option is not applicable for repaired laminates';
                    output_decision = 11;
                elseif app.flag == 2 || (app.flag == 3 && (contains(layup_defect_char{1,1},'ud')==1))
                    output_decision = 16;
                else
                    app.JOBEditField_2.Value = 'There is no UD material in the pristine model';
                    output_decision = 11;
                end
            end

            switch output_decision
                case 1
                    elset = 'EINTERFACE';
                    [app.S11,app.S22,app.S33,app.S12,app.LE11,app.LE22,app.LE33,app.LE12]=Read_ODB_outputs_ele_function2(namefile,elset);

                    if abs(min(app.S11))>max(app.S11)
                        app.S11MPaEditField.Value = min(app.S11)/10^6;% the number is divided by 10^6 so that S11 is in MPa
                    else
                        app.S11MPaEditField.Value = max(app.S11)/10^6;
                    end
                    if abs(min(app.S22))>max(app.S22)
                        app.S22MPaEditField.Value = min(app.S22)/10^6;% the number is divided by 10^6 so that S22 is in MPa
                    else
                        app.S22MPaEditField.Value = max(app.S22)/10^6;
                    end
                    if abs(min(app.S33))>max(app.S33)
                        app.S33MPaEditField.Value = min(app.S33)/10^6;% the number is divided by 10^6 so that S33 is in MPa
                    else
                        app.S33MPaEditField.Value = max(app.S33)/10^6;
                    end
                    if abs(min(app.S12))>max(app.S12)
                        app.S12MPaEditField.Value = min(app.S12)/10^6;% the number is divided by 10^6 so that S12 is in MPa
                    else
                        app.S12MPaEditField.Value = max(app.S12)/10^6;
                    end
                    if abs(min(app.LE11))>max(app.LE11)
                        app.LE11EditField.Value = min(app.LE11);
                    else
                        app.LE11EditField.Value = max(app.LE11)/10^6;
                    end
                    if abs(min(app.LE22))>max(app.LE22)
                        app.LE22EditField.Value = min(app.LE22);
                    else
                        app.LE22EditField.Value = max(app.LE22)/10^6;
                    end
                    if abs(min(app.LE33))>max(app.LE33)
                        app.LE33EditField.Value = min(app.LE33);
                    else
                        app.LE33EditField.Value = max(app.LE33)/10^6;
                    end
                    if abs(min(app.LE12))>max(app.LE12)
                        app.LE12EditField.Value = min(app.LE12);
                    else
                        app.LE12EditField.Value = max(app.LE12)/10^6;
                    end
                    app.JOBEditField_2.Value = 'Done.';
                case 2
                    elset = 'EOVERLAM';
                    [app.S11,app.S22,app.S33,app.S12,app.LE11,app.LE22,app.LE33,app.LE12]=Read_ODB_outputs_ele_function2(namefile,elset);
                    if abs(min(app.S11))>max(app.S11)
                        app.S11MPaEditField.Value = min(app.S11)/10^6;% the number is divided by 10^6 so that S11 is in MPa
                    else
                        app.S11MPaEditField.Value = max(app.S11)/10^6;
                    end
                    if abs(min(app.S22))>max(app.S22)
                        app.S22MPaEditField.Value = min(app.S22)/10^6;% the number is divided by 10^6 so that S22 is in MPa
                    else
                        app.S22MPaEditField.Value = max(app.S22)/10^6;
                    end
                    if abs(min(app.S33))>max(app.S33)
                        app.S33MPaEditField.Value = min(app.S33)/10^6;% the number is divided by 10^6 so that S33 is in MPa
                    else
                        app.S33MPaEditField.Value = max(app.S33)/10^6;
                    end
                    if abs(min(app.S12))>max(app.S12)
                        app.S12MPaEditField.Value = min(app.S12)/10^6;% the number is divided by 10^6 so that S12 is in MPa
                    else
                        app.S12MPaEditField.Value = max(app.S12)/10^6;
                    end
                    if abs(min(app.LE11))>max(app.LE11)
                        app.LE11EditField.Value = min(app.LE11);
                    else
                        app.LE11EditField.Value = max(app.LE11)/10^6;
                    end
                    if abs(min(app.LE22))>max(app.LE22)
                        app.LE22EditField.Value = min(app.LE22);
                    else
                        app.LE22EditField.Value = max(app.LE22)/10^6;
                    end
                    if abs(min(app.LE33))>max(app.LE33)
                        app.LE33EditField.Value = min(app.LE33);
                    else
                        app.LE33EditField.Value = max(app.LE33)/10^6;
                    end
                    if abs(min(app.LE12))>max(app.LE12)
                        app.LE12EditField.Value = min(app.LE12);
                    else
                        app.LE12EditField.Value = max(app.LE12)/10^6;
                    end
                    app.JOBEditField_2.Value = 'Done.';
                case 3
                    elset = 'EPARENTBIAX';
                    [app.S11,app.S22,app.S33,app.S12,app.LE11,app.LE22,app.LE33,app.LE12]=Read_ODB_outputs_ele_function2(namefile,elset);
                    if abs(min(app.S11))>max(app.S11)
                        app.S11MPaEditField.Value = min(app.S11)/10^6;% the number is divided by 10^6 so that S11 is in MPa
                    else
                        app.S11MPaEditField.Value = max(app.S11)/10^6;
                    end
                    if abs(min(app.S22))>max(app.S22)
                        app.S22MPaEditField.Value = min(app.S22)/10^6;% the number is divided by 10^6 so that S22 is in MPa
                    else
                        app.S22MPaEditField.Value = max(app.S22)/10^6;
                    end
                    if abs(min(app.S33))>max(app.S33)
                        app.S33MPaEditField.Value = min(app.S33)/10^6;% the number is divided by 10^6 so that S33 is in MPa
                    else
                        app.S33MPaEditField.Value = max(app.S33)/10^6;
                    end
                    if abs(min(app.S12))>max(app.S12)
                        app.S12MPaEditField.Value = min(app.S12)/10^6;% the number is divided by 10^6 so that S12 is in MPa
                    else
                        app.S12MPaEditField.Value = max(app.S12)/10^6;
                    end
                    if abs(min(app.LE11))>max(app.LE11)
                        app.LE11EditField.Value = min(app.LE11);
                    else
                        app.LE11EditField.Value = max(app.LE11)/10^6;
                    end
                    if abs(min(app.LE22))>max(app.LE22)
                        app.LE22EditField.Value = min(app.LE22);
                    else
                        app.LE22EditField.Value = max(app.LE22)/10^6;
                    end
                    if abs(min(app.LE33))>max(app.LE33)
                        app.LE33EditField.Value = min(app.LE33);
                    else
                        app.LE33EditField.Value = max(app.LE33)/10^6;
                    end
                    if abs(min(app.LE12))>max(app.LE12)
                        app.LE12EditField.Value = min(app.LE12);
                    else
                        app.LE12EditField.Value = max(app.LE12)/10^6;
                    end
                    app.JOBEditField_2.Value = 'Done.';
                case 4
                    elset = 'EPARENTUD';
                    [app.S11,app.S22,app.S33,app.S12,app.LE11,app.LE22,app.LE33,app.LE12]=Read_ODB_outputs_ele_function2(namefile,elset);
                    if abs(min(app.S11))>max(app.S11)
                        app.S11MPaEditField.Value = min(app.S11)/10^6;% the number is divided by 10^6 so that S11 is in MPa
                    else
                        app.S11MPaEditField.Value = max(app.S11)/10^6;
                    end
                    if abs(min(app.S22))>max(app.S22)
                        app.S22MPaEditField.Value = min(app.S22)/10^6;% the number is divided by 10^6 so that S22 is in MPa
                    else
                        app.S22MPaEditField.Value = max(app.S22)/10^6;
                    end
                    if abs(min(app.S33))>max(app.S33)
                        app.S33MPaEditField.Value = min(app.S33)/10^6;% the number is divided by 10^6 so that S33 is in MPa
                    else
                        app.S33MPaEditField.Value = max(app.S33)/10^6;
                    end
                    if abs(min(app.S12))>max(app.S12)
                        app.S12MPaEditField.Value = min(app.S12)/10^6;% the number is divided by 10^6 so that S12 is in MPa
                    else
                        app.S12MPaEditField.Value = max(app.S12)/10^6;
                    end
                    if abs(min(app.LE11))>max(app.LE11)
                        app.LE11EditField.Value = min(app.LE11);
                    else
                        app.LE11EditField.Value = max(app.LE11)/10^6;
                    end
                    if abs(min(app.LE22))>max(app.LE22)
                        app.LE22EditField.Value = min(app.LE22);
                    else
                        app.LE22EditField.Value = max(app.LE22)/10^6;
                    end
                    if abs(min(app.LE33))>max(app.LE33)
                        app.LE33EditField.Value = min(app.LE33);
                    else
                        app.LE33EditField.Value = max(app.LE33)/10^6;
                    end
                    if abs(min(app.LE12))>max(app.LE12)
                        app.LE12EditField.Value = min(app.LE12);
                    else
                        app.LE12EditField.Value = max(app.LE12)/10^6;
                    end
                    app.JOBEditField_2.Value = 'Done.';
                case 5
                    elset = 'EREPAIRBIAX1';
                    [app.S11,app.S22,app.S33,app.S12,app.LE11,app.LE22,app.LE33,app.LE12]=Read_ODB_outputs_ele_function2(namefile,elset);
                    if abs(min(app.S11))>max(app.S11)
                        app.S11MPaEditField.Value = min(app.S11)/10^6;% the number is divided by 10^6 so that S11 is in MPa
                    else
                        app.S11MPaEditField.Value = max(app.S11)/10^6;
                    end
                    if abs(min(app.S22))>max(app.S22)
                        app.S22MPaEditField.Value = min(app.S22)/10^6;% the number is divided by 10^6 so that S22 is in MPa
                    else
                        app.S22MPaEditField.Value = max(app.S22)/10^6;
                    end
                    if abs(min(app.S33))>max(app.S33)
                        app.S33MPaEditField.Value = min(app.S33)/10^6;% the number is divided by 10^6 so that S33 is in MPa
                    else
                        app.S33MPaEditField.Value = max(app.S33)/10^6;
                    end
                    if abs(min(app.S12))>max(app.S12)
                        app.S12MPaEditField.Value = min(app.S12)/10^6;% the number is divided by 10^6 so that S12 is in MPa
                    else
                        app.S12MPaEditField.Value = max(app.S12)/10^6;
                    end
                    if abs(min(app.LE11))>max(app.LE11)
                        app.LE11EditField.Value = min(app.LE11);
                    else
                        app.LE11EditField.Value = max(app.LE11)/10^6;
                    end
                    if abs(min(app.LE22))>max(app.LE22)
                        app.LE22EditField.Value = min(app.LE22);
                    else
                        app.LE22EditField.Value = max(app.LE22)/10^6;
                    end
                    if abs(min(app.LE33))>max(app.LE33)
                        app.LE33EditField.Value = min(app.LE33);
                    else
                        app.LE33EditField.Value = max(app.LE33)/10^6;
                    end
                    if abs(min(app.LE12))>max(app.LE12)
                        app.LE12EditField.Value = min(app.LE12);
                    else
                        app.LE12EditField.Value = max(app.LE12)/10^6;
                    end
                    app.JOBEditField_2.Value = 'Done.';
                case 6
                    elset = 'EREPAIRUD1';
                    [app.S11,app.S22,app.S33,app.S12,app.LE11,app.LE22,app.LE33,app.LE12]=Read_ODB_outputs_ele_function2(namefile,elset);
                    if abs(min(app.S11))>max(app.S11)
                        app.S11MPaEditField.Value = min(app.S11)/10^6;% the number is divided by 10^6 so that S11 is in MPa
                    else
                        app.S11MPaEditField.Value = max(app.S11)/10^6;
                    end
                    if abs(min(app.S22))>max(app.S22)
                        app.S22MPaEditField.Value = min(app.S22)/10^6;% the number is divided by 10^6 so that S22 is in MPa
                    else
                        app.S22MPaEditField.Value = max(app.S22)/10^6;
                    end
                    if abs(min(app.S33))>max(app.S33)
                        app.S33MPaEditField.Value = min(app.S33)/10^6;% the number is divided by 10^6 so that S33 is in MPa
                    else
                        app.S33MPaEditField.Value = max(app.S33)/10^6;
                    end
                    if abs(min(app.S12))>max(app.S12)
                        app.S12MPaEditField.Value = min(app.S12)/10^6;% the number is divided by 10^6 so that S12 is in MPa
                    else
                        app.S12MPaEditField.Value = max(app.S12)/10^6;
                    end
                    if abs(min(app.LE11))>max(app.LE11)
                        app.LE11EditField.Value = min(app.LE11);
                    else
                        app.LE11EditField.Value = max(app.LE11)/10^6;
                    end
                    if abs(min(app.LE22))>max(app.LE22)
                        app.LE22EditField.Value = min(app.LE22);
                    else
                        app.LE22EditField.Value = max(app.LE22)/10^6;
                    end
                    if abs(min(app.LE33))>max(app.LE33)
                        app.LE33EditField.Value = min(app.LE33);
                    else
                        app.LE33EditField.Value = max(app.LE33)/10^6;
                    end
                    if abs(min(app.LE12))>max(app.LE12)
                        app.LE12EditField.Value = min(app.LE12);
                    else
                        app.LE12EditField.Value = max(app.LE12)/10^6;
                    end
                    app.JOBEditField_2.Value = 'Done.';
                case 7
                    elset = 'RESIN';
                    [app.S11,app.S22,app.S33,app.S12,app.LE11,app.LE22,app.LE33,app.LE12]=Read_ODB_outputs_ele_function2(namefile,elset);
                    if abs(min(app.S11))>max(app.S11)
                        app.S11MPaEditField.Value = min(app.S11)/10^6;% the number is divided by 10^6 so that S11 is in MPa
                    else
                        app.S11MPaEditField.Value = max(app.S11)/10^6;
                    end
                    if abs(min(app.S22))>max(app.S22)
                        app.S22MPaEditField.Value = min(app.S22)/10^6;% the number is divided by 10^6 so that S22 is in MPa
                    else
                        app.S22MPaEditField.Value = max(app.S22)/10^6;
                    end
                    if abs(min(app.S33))>max(app.S33)
                        app.S33MPaEditField.Value = min(app.S33)/10^6;% the number is divided by 10^6 so that S33 is in MPa
                    else
                        app.S33MPaEditField.Value = max(app.S33)/10^6;
                    end
                    if abs(min(app.S12))>max(app.S12)
                        app.S12MPaEditField.Value = min(app.S12)/10^6;% the number is divided by 10^6 so that S12 is in MPa
                    else
                        app.S12MPaEditField.Value = max(app.S12)/10^6;
                    end
                    if abs(min(app.LE11))>max(app.LE11)
                        app.LE11EditField.Value = min(app.LE11);
                    else
                        app.LE11EditField.Value = max(app.LE11)/10^6;
                    end
                    if abs(min(app.LE22))>max(app.LE22)
                        app.LE22EditField.Value = min(app.LE22);
                    else
                        app.LE22EditField.Value = max(app.LE22)/10^6;
                    end
                    if abs(min(app.LE33))>max(app.LE33)
                        app.LE33EditField.Value = min(app.LE33);
                    else
                        app.LE33EditField.Value = max(app.LE33)/10^6;
                    end
                    if abs(min(app.LE12))>max(app.LE12)
                        app.LE12EditField.Value = min(app.LE12);
                    else
                        app.LE12EditField.Value = max(app.LE12)/10^6;
                    end
                    app.JOBEditField_2.Value = 'Done.';
                case 8
                    elset = 'RESINRICH';
                    [app.S11,app.S22,app.S33,app.S12,app.LE11,app.LE22,app.LE33,app.LE12]=Read_ODB_outputs_ele_function2(namefile,elset);
                    if abs(min(app.S11))>max(app.S11)
                        app.S11MPaEditField.Value = min(app.S11)/10^6;% the number is divided by 10^6 so that S11 is in MPa
                    else
                        app.S11MPaEditField.Value = max(app.S11)/10^6;
                    end
                    if abs(min(app.S22))>max(app.S22)
                        app.S22MPaEditField.Value = min(app.S22)/10^6;% the number is divided by 10^6 so that S22 is in MPa
                    else
                        app.S22MPaEditField.Value = max(app.S22)/10^6;
                    end
                    if abs(min(app.S33))>max(app.S33)
                        app.S33MPaEditField.Value = min(app.S33)/10^6;% the number is divided by 10^6 so that S33 is in MPa
                    else
                        app.S33MPaEditField.Value = max(app.S33)/10^6;
                    end
                    if abs(min(app.S12))>max(app.S12)
                        app.S12MPaEditField.Value = min(app.S12)/10^6;% the number is divided by 10^6 so that S12 is in MPa
                    else
                        app.S12MPaEditField.Value = max(app.S12)/10^6;
                    end
                    if abs(min(app.LE11))>max(app.LE11)
                        app.LE11EditField.Value = min(app.LE11);
                    else
                        app.LE11EditField.Value = max(app.LE11)/10^6;
                    end
                    if abs(min(app.LE22))>max(app.LE22)
                        app.LE22EditField.Value = min(app.LE22);
                    else
                        app.LE22EditField.Value = max(app.LE22)/10^6;
                    end
                    if abs(min(app.LE33))>max(app.LE33)
                        app.LE33EditField.Value = min(app.LE33);
                    else
                        app.LE33EditField.Value = max(app.LE33)/10^6;
                    end
                    if abs(min(app.LE12))>max(app.LE12)
                        app.LE12EditField.Value = min(app.LE12);
                    else
                        app.LE12EditField.Value = max(app.LE12)/10^6;
                    end
                    app.JOBEditField_2.Value = 'Done.';
                case 9
                    elset = 'EREPAIRBIAX';
                    [app.S11,app.S22,app.S33,app.S12,app.LE11,app.LE22,app.LE33,app.LE12]=Read_ODB_outputs_ele_function2(namefile,elset);
                    if abs(min(app.S11))>max(app.S11)
                        app.S11MPaEditField.Value = min(app.S11)/10^6;% the number is divided by 10^6 so that S11 is in MPa
                    else
                        app.S11MPaEditField.Value = max(app.S11)/10^6;
                    end
                    if abs(min(app.S22))>max(app.S22)
                        app.S22MPaEditField.Value = min(app.S22)/10^6;% the number is divided by 10^6 so that S22 is in MPa
                    else
                        app.S22MPaEditField.Value = max(app.S22)/10^6;
                    end
                    if abs(min(app.S33))>max(app.S33)
                        app.S33MPaEditField.Value = min(app.S33)/10^6;% the number is divided by 10^6 so that S33 is in MPa
                    else
                        app.S33MPaEditField.Value = max(app.S33)/10^6;
                    end
                    if abs(min(app.S12))>max(app.S12)
                        app.S12MPaEditField.Value = min(app.S12)/10^6;% the number is divided by 10^6 so that S12 is in MPa
                    else
                        app.S12MPaEditField.Value = max(app.S12)/10^6;
                    end
                    if abs(min(app.LE11))>max(app.LE11)
                        app.LE11EditField.Value = min(app.LE11);
                    else
                        app.LE11EditField.Value = max(app.LE11)/10^6;
                    end
                    if abs(min(app.LE22))>max(app.LE22)
                        app.LE22EditField.Value = min(app.LE22);
                    else
                        app.LE22EditField.Value = max(app.LE22)/10^6;
                    end
                    if abs(min(app.LE33))>max(app.LE33)
                        app.LE33EditField.Value = min(app.LE33);
                    else
                        app.LE33EditField.Value = max(app.LE33)/10^6;
                    end
                    if abs(min(app.LE12))>max(app.LE12)
                        app.LE12EditField.Value = min(app.LE12);
                    else
                        app.LE12EditField.Value = max(app.LE12)/10^6;
                    end
                    app.JOBEditField_2.Value = 'Done.';
                case 10
                    elset = 'EREPAIRUD';
                    [app.S11,app.S22,app.S33,app.S12,app.LE11,app.LE22,app.LE33,app.LE12]=Read_ODB_outputs_ele_function2(namefile,elset);
                    if abs(min(app.S11))>max(app.S11)
                        app.S11MPaEditField.Value = min(app.S11)/10^6;% the number is divided by 10^6 so that S11 is in MPa
                    else
                        app.S11MPaEditField.Value = max(app.S11)/10^6;
                    end
                    if abs(min(app.S22))>max(app.S22)
                        app.S22MPaEditField.Value = min(app.S22)/10^6;% the number is divided by 10^6 so that S22 is in MPa
                    else
                        app.S22MPaEditField.Value = max(app.S22)/10^6;
                    end
                    if abs(min(app.S33))>max(app.S33)
                        app.S33MPaEditField.Value = min(app.S33)/10^6;% the number is divided by 10^6 so that S33 is in MPa
                    else
                        app.S33MPaEditField.Value = max(app.S33)/10^6;
                    end
                    if abs(min(app.S12))>max(app.S12)
                        app.S12MPaEditField.Value = min(app.S12)/10^6;% the number is divided by 10^6 so that S12 is in MPa
                    else
                        app.S12MPaEditField.Value = max(app.S12)/10^6;
                    end
                    if abs(min(app.LE11))>max(app.LE11)
                        app.LE11EditField.Value = min(app.LE11);
                    else
                        app.LE11EditField.Value = max(app.LE11)/10^6;
                    end
                    if abs(min(app.LE22))>max(app.LE22)
                        app.LE22EditField.Value = min(app.LE22);
                    else
                        app.LE22EditField.Value = max(app.LE22)/10^6;
                    end
                    if abs(min(app.LE33))>max(app.LE33)
                        app.LE33EditField.Value = min(app.LE33);
                    else
                        app.LE33EditField.Value = max(app.LE33)/10^6;
                    end
                    if abs(min(app.LE12))>max(app.LE12)
                        app.LE12EditField.Value = min(app.LE12);
                    else
                        app.LE12EditField.Value = max(app.LE12)/10^6;
                    end
                    app.JOBEditField_2.Value = 'Done.';

                case 11
                    % case of errors
                    app.S11MPaEditField.Value = 0;% the number is divided by 10^6 so that S11 is in MPa
                    app.S22MPaEditField.Value = 0;% the number is divided by 10^6 so that S22 is in MPa
                    app.S33MPaEditField.Value = 0;% the number is divided by 10^6 so that S33 is in MPa
                    app.S12MPaEditField.Value = 0;% the number is divided by 10^6 so that S12 is in MPa
                    app.LE11EditField.Value = 0;
                    app.LE22EditField.Value = 0;
                    app.LE33EditField.Value = 0;
                    app.LE12EditField.Value = 0;


                    % Cases of Cohesive elements
                case 12
                    elset = 'EINTERFACE';
                    [app.S22,app.S12,app.LE22,app.LE12]=Read_ODB_outputs_ele_function3(namefile,elset);
                    app.S11MPaEditField.Value = 0;% the number is divided by 10^6 so that S11 is in MPa
                    if abs(min(app.S22))>max(app.S22)
                        app.S22MPaEditField.Value = min(app.S22)/10^6;% the number is divided by 10^6 so that S22 is in MPa
                    else
                        app.S22MPaEditField.Value = max(app.S22)/10^6;
                    end
                    app.S33MPaEditField.Value = 0;% the number is divided by 10^6 so that S33 is in MPa
                    if abs(min(app.S12))>max(app.S12)
                        app.S12MPaEditField.Value = min(app.S12)/10^6;% the number is divided by 10^6 so that S12 is in MPa
                    else
                        app.S12MPaEditField.Value = max(app.S12)/10^6;
                    end
                    app.LE11EditField.Value = 0;
                    if abs(min(app.LE22))>max(app.LE22)
                        app.LE22EditField.Value = min(app.LE22);
                    else
                        app.LE22EditField.Value = max(app.LE22)/10^6;
                    end
                    app.LE33EditField.Value = 0;
                    if abs(min(app.LE12))>max(app.LE12)
                        app.LE12EditField.Value = min(app.LE12);
                    else
                        app.LE12EditField.Value = max(app.LE12)/10^6;
                    end

                case 13
                    elset = 'RESIN';
                    [app.S22,app.S12,app.LE22,app.LE12]=Read_ODB_outputs_ele_function3(namefile,elset);
                    app.S11MPaEditField.Value = 0;% the number is divided by 10^6 so that S11 is in MPa
                    if abs(min(app.S22))>max(app.S22)
                        app.S22MPaEditField.Value = min(app.S22)/10^6;% the number is divided by 10^6 so that S22 is in MPa
                    else
                        app.S22MPaEditField.Value = max(app.S22)/10^6;
                    end
                    app.S33MPaEditField.Value = 0;% the number is divided by 10^6 so that S33 is in MPa
                    if abs(min(app.S12))>max(app.S12)
                        app.S12MPaEditField.Value = min(app.S12)/10^6;% the number is divided by 10^6 so that S12 is in MPa
                    else
                        app.S12MPaEditField.Value = max(app.S12)/10^6;
                    end
                    app.LE11EditField.Value = 0;
                    if abs(min(app.LE22))>max(app.LE22)
                        app.LE22EditField.Value = min(app.LE22);
                    else
                        app.LE22EditField.Value = max(app.LE22)/10^6;
                    end
                    app.LE33EditField.Value = 0;
                    if abs(min(app.LE12))>max(app.LE12)
                        app.LE12EditField.Value = min(app.LE12);
                    else
                        app.LE12EditField.Value = max(app.LE12)/10^6;
                    end


                case 14
                    elset = 'RESINRICH';
                    [app.S22,app.S12,app.LE22,app.LE12]=Read_ODB_outputs_ele_function3(namefile,elset);
                    app.S11MPaEditField.Value = 0;% the number is divided by 10^6 so that S11 is in MPa
                    if abs(min(app.S22))>max(app.S22)
                        app.S22MPaEditField.Value = min(app.S22)/10^6;% the number is divided by 10^6 so that S22 is in MPa
                    else
                        app.S22MPaEditField.Value = max(app.S22)/10^6;
                    end
                    app.S33MPaEditField.Value = 0;% the number is divided by 10^6 so that S33 is in MPa
                    if abs(min(app.S12))>max(app.S12)
                        app.S12MPaEditField.Value = min(app.S12)/10^6;% the number is divided by 10^6 so that S12 is in MPa
                    else
                        app.S12MPaEditField.Value = max(app.S12)/10^6;
                    end
                    app.LE11EditField.Value = 0;
                    if abs(min(app.LE22))>max(app.LE22)
                        app.LE22EditField.Value = min(app.LE22);
                    else
                        app.LE22EditField.Value = max(app.LE22)/10^6;
                    end
                    app.LE33EditField.Value = 0;
                    if abs(min(app.LE12))>max(app.LE12)
                        app.LE12EditField.Value = min(app.LE12);
                    else
                        app.LE12EditField.Value = max(app.LE12)/10^6;
                    end

                case 15
                    elset = 'ECOMPOSITEBIAX';
                    [app.S11,app.S22,app.S33,app.S12,app.LE11,app.LE22,app.LE33,app.LE12]=Read_ODB_outputs_ele_function2(namefile,elset);
                    if abs(min(app.S11))>max(app.S11)
                        app.S11MPaEditField.Value = min(app.S11)/10^6;% the number is divided by 10^6 so that S11 is in MPa
                    else
                        app.S11MPaEditField.Value = max(app.S11)/10^6;
                    end
                    if abs(min(app.S22))>max(app.S22)
                        app.S22MPaEditField.Value = min(app.S22)/10^6;% the number is divided by 10^6 so that S22 is in MPa
                    else
                        app.S22MPaEditField.Value = max(app.S22)/10^6;
                    end
                    if abs(min(app.S33))>max(app.S33)
                        app.S33MPaEditField.Value = min(app.S33)/10^6;% the number is divided by 10^6 so that S33 is in MPa
                    else
                        app.S33MPaEditField.Value = max(app.S33)/10^6;
                    end
                    if abs(min(app.S12))>max(app.S12)
                        app.S12MPaEditField.Value = min(app.S12)/10^6;% the number is divided by 10^6 so that S12 is in MPa
                    else
                        app.S12MPaEditField.Value = max(app.S12)/10^6;
                    end
                    if abs(min(app.LE11))>max(app.LE11)
                        app.LE11EditField.Value = min(app.LE11);
                    else
                        app.LE11EditField.Value = max(app.LE11)/10^6;
                    end
                    if abs(min(app.LE22))>max(app.LE22)
                        app.LE22EditField.Value = min(app.LE22);
                    else
                        app.LE22EditField.Value = max(app.LE22)/10^6;
                    end
                    if abs(min(app.LE33))>max(app.LE33)
                        app.LE33EditField.Value = min(app.LE33);
                    else
                        app.LE33EditField.Value = max(app.LE33)/10^6;
                    end
                    if abs(min(app.LE12))>max(app.LE12)
                        app.LE12EditField.Value = min(app.LE12);
                    else
                        app.LE12MPEditField.Value = max(app.LE12)/10^6;
                    end
                    app.JOBEditField_2.Value = 'Done.';

                case 16
                    elset = 'ECOMPOSITEUD';
                    [app.S11,app.S22,app.S33,app.S12,app.LE11,app.LE22,app.LE33,app.LE12]=Read_ODB_outputs_ele_function2(namefile,elset);
                    if abs(min(app.S11))>max(app.S11)
                        app.S11MPaEditField.Value = min(app.S11)/10^6;% the number is divided by 10^6 so that S11 is in MPa
                    else
                        app.S11MPaEditField.Value = max(app.S11)/10^6;
                    end
                    if abs(min(app.S22))>max(app.S22)
                        app.S22MPaEditField.Value = min(app.S22)/10^6;% the number is divided by 10^6 so that S22 is in MPa
                    else
                        app.S22MPaEditField.Value = max(app.S22)/10^6;
                    end
                    if abs(min(app.S33))>max(app.S33)
                        app.S33MPaEditField.Value = min(app.S33)/10^6;% the number is divided by 10^6 so that S33 is in MPa
                    else
                        app.S33MPaEditField.Value = max(app.S33)/10^6;
                    end
                    if abs(min(app.S12))>max(app.S12)
                        app.S12MPaEditField.Value = min(app.S12)/10^6;% the number is divided by 10^6 so that S12 is in MPa
                    else
                        app.S12MPaEditField.Value = max(app.S12)/10^6;
                    end
                    if abs(min(app.LE11))>max(app.LE11)
                        app.LE11EditField.Value = min(app.LE11);
                    else
                        app.LE11EditField.Value = max(app.LE11)/10^6;
                    end
                    if abs(min(app.LE22))>max(app.LE22)
                        app.LE22EditField.Value = min(app.LE22);
                    else
                        app.LE22EditField.Value = max(app.LE22)/10^6;
                    end
                    if abs(min(app.LE33))>max(app.LE33)
                        app.LE33EditField.Value = min(app.LE33);
                    else
                        app.LE33EditField.Value = max(app.LE33)/10^6;
                    end
                    if abs(min(app.LE12))>max(app.LE12)
                        app.LE12EditField.Value = min(app.LE12);
                    else
                        app.LE12EditField.Value = max(app.LE12)/10^6;
                    end
                    app.JOBEditField_2.Value = 'Done.';

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeneralInputTab = uitab(app.TabGroup);
            app.GeneralInputTab.Title = 'General Input';
            app.Panel_3 = uipanel(app.GeneralInputTab);
            app.LoadingDropDownLabel = uilabel(app.Panel_3);
            app.LoadingDropDownLabel.Text = 'Loading';
            app.LoadingDropDown = uidropdown(app.Panel_3);
            app.LoadingDropDown.Items = {'0: Tension', '1: Bending'};
            app.LoadingDropDown.Value = '0: Tension';
            app.ValueLabel = uilabel(app.Panel_3);
            app.ValueLabel.Text = 'Value';
            app.UR3rotationaldisplacementradEditField_2Label = uilabel(app.Panel_3);
            app.UR3rotationaldisplacementradEditField_2Label.Text = 'UR3 rotational displacement [rad]';
            app.UR3rotationaldisplacementradEditField_2 = uieditfield(app.Panel_3, 'numeric');
            app.UR3rotationaldisplacementradEditField_2.Value = 0.1745;
            app.EditFieldLabel = uilabel(app.Panel_3);
            app.EditFieldLabel.Text = 'U1 Displacement in X (mm)';
            app.U1DisplacementinXmmEditField_2 = uieditfield(app.Panel_3, 'numeric');
            app.U1DisplacementinXmmEditField_2.Value = 10;
            app.LoadingLabel = uilabel(app.GeneralInputTab);
            app.LoadingLabel.Text = 'Loading';
            app.OutputFilenameLabel = uilabel(app.GeneralInputTab);
            app.OutputFilenameLabel.Text = ' Output File name';
            app.Panel_4 = uipanel(app.GeneralInputTab);
            app.FilenameEditFieldLabel = uilabel(app.Panel_4);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.Panel_4, 'text');
            app.FilenameEditField.Value = 'BUBU_thk_1_3e14_sdeg_095';
            app.InputFilenameLabel = uilabel(app.GeneralInputTab);
            app.InputFilenameLabel.Text = ' Input File name';
            app.Panel_16 = uipanel(app.GeneralInputTab);
            app.FilenameEditField_2Label = uilabel(app.Panel_16);
            app.FilenameEditField_2Label.Text = 'File name';
            app.FilenameEditField_2 = uieditfield(app.Panel_16, 'text');
            app.FilenameEditField_2.Value = 'CASE1.xlsx';
            app.SheetEditFieldLabel = uilabel(app.Panel_16);
            app.SheetEditFieldLabel.Text = 'Sheet';
            app.SheetEditField = uieditfield(app.Panel_16, 'numeric');
            app.SheetEditField.Value = 1;
            app.Panel_35 = uipanel(app.GeneralInputTab);
            app.PlymatchDropDownLabel = uilabel(app.Panel_35);
            app.PlymatchDropDownLabel.Text = 'Ply match';
            app.PlymatchDropDown = uidropdown(app.Panel_35);
            app.PlymatchDropDown.Items = {'0: ply by ply match', '1: ply by ply mismatch'};
            app.PlymatchDropDown.Tooltip = {'The material types must be different.'};
            app.PlymatchDropDown.Value = '1: ply by ply mismatch';
            app.PlybyplymatchLabel = uilabel(app.GeneralInputTab);
            app.PlybyplymatchLabel.Text = 'Ply by ply match';
            app.Panel_36 = uipanel(app.GeneralInputTab);
            app.WidthofthedefectmmEditFieldLabel = uilabel(app.Panel_36);
            app.WidthofthedefectmmEditFieldLabel.Text = 'Width of the defect (mm)';
            app.WidthofthedefectmmEditField = uieditfield(app.Panel_36, 'numeric');
            app.WidthofthedefectmmEditField.Value = 1;
            app.WidthofdefectLabel = uilabel(app.GeneralInputTab);
            app.WidthofdefectLabel.Text = 'Width of defect';
            app.Panel_37 = uipanel(app.GeneralInputTab);
            app.OverlaminaDropDownLabel = uilabel(app.Panel_37);
            app.OverlaminaDropDownLabel.Text = 'Overlamina';
            app.OverlaminaDropDown = uidropdown(app.Panel_37);
            app.OverlaminaDropDown.Items = {'Yes', 'No'};
            app.OverlaminaDropDown.Value = 'No';
            app.OverlappingoftheoverlaminaDropDownLabel = uilabel(app.Panel_37);
            app.OverlappingoftheoverlaminaDropDownLabel.Text = 'Overlapping of the overlamina';
            app.OverlappingoftheoverlaminaDropDown = uidropdown(app.Panel_37);
            app.OverlappingoftheoverlaminaDropDown.Items = {'Yes', 'No'};
            app.OverlappingoftheoverlaminaDropDown.Value = 'No';
            app.OverlaminaAdditionontopofrepairLabel = uilabel(app.GeneralInputTab);
            app.OverlaminaAdditionontopofrepairLabel.Text = 'Overlamina Addition on top of repair';
            app.Panel_38 = uipanel(app.GeneralInputTab);
            app.ParabolacurveEditFieldLabel = uilabel(app.Panel_38);
            app.ParabolacurveEditFieldLabel.Text = 'Parabola curve';
            app.ParabolacurveEditField = uieditfield(app.Panel_38, 'numeric');
            app.CurveDropDownLabel = uilabel(app.Panel_38);
            app.CurveDropDownLabel.Text = 'Curve';
            app.CurveDropDown = uidropdown(app.Panel_38);
            app.CurveDropDown.Items = {'Yes', 'No'};
            app.CurveDropDown.Value = 'No';
            app.CurvepanelLabel = uilabel(app.GeneralInputTab);
            app.CurvepanelLabel.Text = 'Curve panel';
            app.ResinElementLabel = uilabel(app.GeneralInputTab);
            app.ResinElementLabel.Text = 'Resin Element';
            app.Panel_39 = uipanel(app.GeneralInputTab);
            app.TypeofelementDropDownLabel = uilabel(app.Panel_39);
            app.TypeofelementDropDownLabel.Text = 'Type of element';
            app.TypeofelementDropDown = uidropdown(app.Panel_39);
            app.TypeofelementDropDown.Items = {'0: Homogeneous elements', '1: Cohesive elements'};
            app.TypeofelementDropDown.Value = '1: Cohesive elements';
            app.Panel_43 = uipanel(app.GeneralInputTab);
            app.amplitudeEditFieldLabel = uilabel(app.Panel_43);
            app.amplitudeEditFieldLabel.Text = 'amplitude';
            app.amplitudeEditField = uieditfield(app.Panel_43, 'numeric');
            app.amplitudeEditField.Value = 8;
            app.centreEditFieldLabel = uilabel(app.Panel_43);
            app.centreEditFieldLabel.Text = 'centre';
            app.centreEditField = uieditfield(app.Panel_43, 'numeric');
            app.centreEditField.Value = 250;
            app.sigmaEditFieldLabel = uilabel(app.Panel_43);
            app.sigmaEditFieldLabel.Text = 'sigma';
            app.sigmaEditField = uieditfield(app.Panel_43, 'numeric');
            app.sigmaEditField.Value = 8;
            app.GaussianparametersguessLabel = uilabel(app.GeneralInputTab);
            app.GaussianparametersguessLabel.Text = 'Gaussian parameters guess';
            app.Panel_42 = uipanel(app.GeneralInputTab);
            app.CPUcoresEditFieldLabel = uilabel(app.Panel_42);
            app.CPUcoresEditFieldLabel.Text = 'CPU cores';
            app.CPUcoresEditField = uieditfield(app.Panel_42, 'numeric');
            app.CPUcoresEditField.Value = 16;
            app.NumberofCPUcoresusedforthesimulationLabel = uilabel(app.GeneralInputTab);
            app.NumberofCPUcoresusedforthesimulationLabel.Text = 'Number of CPU cores used for the simulation';
            app.Input2 = uitab(app.TabGroup);
            app.Input2.Title = 'Geometry';
            app.Panel_7 = uipanel(app.Input2);
            app.DiscretisingdensityEditFieldLabel = uilabel(app.Panel_7);
            app.DiscretisingdensityEditFieldLabel.Text = 'Discretising density';
            app.DiscretisingdensityEditField = uieditfield(app.Panel_7, 'numeric');
            app.DiscretisingdensityEditField.Value = 4000;
            app.DiscretisingdensityLabel = uilabel(app.Input2);
            app.DiscretisingdensityLabel.Text = 'Discretising density ';
            app.Panel_8 = uipanel(app.Input2);
            app.WidthfrommiddleofdefectmmEditFieldLabel = uilabel(app.Panel_8);
            app.WidthfrommiddleofdefectmmEditFieldLabel.Text = 'Width from middle of defect (mm)';
            app.WidthfrommiddleofdefectmmEditField = uieditfield(app.Panel_8, 'numeric');
            app.WidthfrommiddleofdefectmmEditField.Value = 250;
            app.AdditionalallowanceattheedgesmmEditFieldLabel = uilabel(app.Panel_8);
            app.AdditionalallowanceattheedgesmmEditFieldLabel.Text = 'Additional allowance at the edges (mm)';
            app.AdditionalallowanceattheedgesmmEditField = uieditfield(app.Panel_8, 'numeric');
            app.AdditionalallowanceattheedgesmmEditField.Value = 50;
            app.Geometryof2DmodelLabel_2 = uilabel(app.Input2);
            app.Geometryof2DmodelLabel_2.Text = 'Geometry of 2D model';
            app.Panel_12 = uipanel(app.Input2);
            app.NoteTextArea = uitextarea(app.Panel_12);
            app.NoteTextArea.Value = {'It represents the grid size of each area in which the repair is divided into. e.g. Layup = ud,biax,ud,biax,ud,biax.'; 'Width vector = 80,45,65,45,45,45,80'};
            app.WidthvectorEditFieldLabel = uilabel(app.Panel_12);
            app.WidthvectorEditFieldLabel.Text = 'Width vector';
            app.WidthvectorEditField = uieditfield(app.Panel_12, 'text');
            app.WidthvectorEditField.Value = '150,150,150,150,150,150,150,150,150';
            app.ElementnumberLabel = uilabel(app.Input2);
            app.ElementnumberLabel.Text = 'Element number';
            app.Panel_40 = uipanel(app.Input2);
            app.ThicknessofUDfibrelaminammEditFieldLabel = uilabel(app.Panel_40);
            app.ThicknessofUDfibrelaminammEditFieldLabel.Text = 'Thickness of UD fibre lamina (mm)';
            app.ThicknessofUDfibrelaminammEditField = uieditfield(app.Panel_40, 'numeric');
            app.ThicknessofUDfibrelaminammEditField.Tooltip = {'it can''t be exactly the same number as the BIAX thickness'};
            app.ThicknessofUDfibrelaminammEditField.Value = 0.672;
            app.ThicknessofresininterfaceinbetweenlayersmmEditFieldLabel = uilabel(app.Panel_40);
            app.ThicknessofresininterfaceinbetweenlayersmmEditFieldLabel.Text = 'Thickness of resin interface in between layers (mm)';
            app.ThicknessofresininterfaceinbetweenlayersmmEditField = uieditfield(app.Panel_40, 'numeric');
            app.ThicknessofresininterfaceinbetweenlayersmmEditField.Value = 0.2;
            app.ThicknessofBIAXfibrelaminammEditFieldLabel = uilabel(app.Panel_40);
            app.ThicknessofBIAXfibrelaminammEditFieldLabel.Text = 'Thickness of BIAX fibre lamina (mm)';
            app.ThicknessofBIAXfibrelaminammEditField = uieditfield(app.Panel_40, 'numeric');
            app.ThicknessofBIAXfibrelaminammEditField.Tooltip = {'it can''t be exactly the same number as the UD thickness'};
            app.ThicknessofBIAXfibrelaminammEditField.Value = 0.6721;
            app.ThicknessofresininbetweenrepairandparentlaminateEditFieldLabel = uilabel(app.Panel_40);
            app.ThicknessofresininbetweenrepairandparentlaminateEditFieldLabel.Text = 'Thickness of resin in between repair and parent laminate (mm)';
            app.ThicknessofresininbetweenrepairandparentlaminatemmEditField = uieditfield(app.Panel_40, 'numeric');
            app.ThicknessofresininbetweenrepairandparentlaminatemmEditField.Value = 0.2;
            app.ThicknessoftheoverlaminabiaxorudEditFieldLabel = uilabel(app.Panel_40);
            app.ThicknessoftheoverlaminabiaxorudEditFieldLabel.Text = 'Thickness of the overlamina (biax or ud)';
            app.ThicknessoftheoverlaminabiaxorudEditField = uieditfield(app.Panel_40, 'text');
            app.ThicknessoftheoverlaminabiaxorudEditField.Value = 'biax';
            app.DefinematerialthicknessLabel = uilabel(app.Input2);
            app.DefinematerialthicknessLabel.Text = 'Define material thickness';
            app.Panel_41 = uipanel(app.Input2);
            app.BIAXoverlapmmEditFieldLabel = uilabel(app.Panel_41);
            app.BIAXoverlapmmEditFieldLabel.Text = 'BIAX overlap (mm)';
            app.BIAXoverlapmmEditField = uieditfield(app.Panel_41, 'numeric');
            app.BIAXoverlapmmEditField.Value = 40.32;
            app.OverlaminaoverlapmmEditFieldLabel = uilabel(app.Panel_41);
            app.OverlaminaoverlapmmEditFieldLabel.Text = 'Overlamina overlap (mm)';
            app.OverlaminaoverlapmmEditField = uieditfield(app.Panel_41, 'numeric');
            app.OverlaminaoverlapmmEditField.Value = 48;
            app.UDoverlapmmEditFieldLabel = uilabel(app.Panel_41);
            app.UDoverlapmmEditFieldLabel.Text = 'UD overlap (mm)';
            app.UDoverlapmmEditField = uieditfield(app.Panel_41, 'numeric');
            app.UDoverlapmmEditField.Value = 40.32;
            app.OverlappinglayerlengthLabel = uilabel(app.Input2);
            app.OverlappinglayerlengthLabel.Text = 'Overlapping layer length ';
            app.LayupSelectionProcessLabel = uilabel(app.Input2);
            app.LayupSelectionProcessLabel.Text = 'Layup Selection Process';
            app.Panel_44 = uipanel(app.Input2);
            app.LayupwithdefectegudbiaxudEditFieldLabel = uilabel(app.Panel_44);
            app.LayupwithdefectegudbiaxudEditFieldLabel.Text = 'Layup with defect e.g. ud,biax,ud';
            app.LayupwithdefectegudbiaxudEditField = uieditfield(app.Panel_44, 'text');
            app.LayupwithdefectegudbiaxudEditField.Tooltip = {'From top to bottom e.g.'; '1st ply:------- ud ----------'; '2nd ply:------ biax --------'; '3rd ply:------- biax --------'; '4th ply:------- ud ----------'; ''};
            app.LayupwithdefectegudbiaxudEditField.Value = 'biax,ud,biax,ud';
            app.LayupwithoutdefectegudbiaxudEditFieldLabel = uilabel(app.Panel_44);
            app.LayupwithoutdefectegudbiaxudEditFieldLabel.Text = 'Layup without defect e.g. ud,biax,ud';
            app.LayupwithoutdefectegudbiaxudEditField = uieditfield(app.Panel_44, 'text');
            app.LayupwithoutdefectegudbiaxudEditField.Tooltip = {'From top to bottom e.g.'; '1st ply:------- ud ----------'; '2nd ply:------ biax --------'; '3rd ply:------- biax --------'; '4th ply:------- ud ----------'};
            app.LayupwithoutdefectegudbiaxudEditField.Value = 'ud,biax,ud,biax';
            app.FlagDropDownLabel = uilabel(app.Panel_44);
            app.FlagDropDownLabel.Text = 'Flag';
            app.FlagDropDown = uidropdown(app.Panel_44);
            app.FlagDropDown.Items = {'0: Composite with defect and layup contains two different lamina materials', '1: Composite with defect and layup contains one type of lamina material', '2: Composite without defect and layup contains two different lamina materials', '3: Composite without defect and layup contains one type of lamina material'};
            app.FlagDropDown.Tooltip = {'It should match the Layup with/without defect configuration'};
            app.FlagDropDown.Value = '0: Composite with defect and layup contains two different lamina materials';
            app.FromtoptobottomLabel = uilabel(app.Panel_44);
            app.FromtoptobottomLabel.Text = 'From top to bottom';
            app.ParentmaterialDropDownLabel = uilabel(app.Panel_44);
            app.ParentmaterialDropDownLabel.Text = 'Parent material';
            app.ParentmaterialDropDown = uidropdown(app.Panel_44);
            app.ParentmaterialDropDown.Items = {'0: with the same material in repair and parent', '1: with different material'};
            app.ParentmaterialDropDown.Value = '0: with the same material in repair and parent';
            app.MaterialpropertiesTab = uitab(app.TabGroup);
            app.MaterialpropertiesTab.Title = 'Material properties';
            app.Panel_21 = uipanel(app.MaterialpropertiesTab);
            app.E1PaEditFieldLabel = uilabel(app.Panel_21);
            app.E1PaEditFieldLabel.Text = 'E1 (Pa)';
            app.E1PaEditField = uieditfield(app.Panel_21, 'numeric');
            app.E1PaEditField.Value = 41630000000;
            app.E2PaEditFieldLabel = uilabel(app.Panel_21);
            app.E2PaEditFieldLabel.Text = 'E2 (Pa)';
            app.E2PaEditField = uieditfield(app.Panel_21, 'numeric');
            app.E2PaEditField.Value = 14930000000;
            app.E3PaEditFieldLabel = uilabel(app.Panel_21);
            app.E3PaEditFieldLabel.Text = 'E3 (Pa)';
            app.E3PaEditField = uieditfield(app.Panel_21, 'numeric');
            app.E3PaEditField.Value = 13430000000;
            app.V12EditFieldLabel = uilabel(app.Panel_21);
            app.V12EditFieldLabel.Text = 'V12';
            app.V12EditField = uieditfield(app.Panel_21, 'numeric');
            app.V12EditField.Value = 0.241;
            app.V13EditFieldLabel = uilabel(app.Panel_21);
            app.V13EditFieldLabel.Text = 'V13';
            app.V13EditField = uieditfield(app.Panel_21, 'numeric');
            app.V13EditField.Value = 0.2675;
            app.V23EditFieldLabel = uilabel(app.Panel_21);
            app.V23EditFieldLabel.Text = 'V23';
            app.V23EditField = uieditfield(app.Panel_21, 'numeric');
            app.V23EditField.Value = 0.3301;
            app.G12PaEditFieldLabel = uilabel(app.Panel_21);
            app.G12PaEditFieldLabel.Text = 'G12(Pa)';
            app.G12PaEditField = uieditfield(app.Panel_21, 'numeric');
            app.G12PaEditField.Value = 5047000000;
            app.G13PaEditFieldLabel = uilabel(app.Panel_21);
            app.G13PaEditFieldLabel.Text = 'G13(Pa)';
            app.G13PaEditField = uieditfield(app.Panel_21, 'numeric');
            app.G13PaEditField.Value = 5047000000;
            app.G23PaEditFieldLabel = uilabel(app.Panel_21);
            app.G23PaEditFieldLabel.Text = 'G23(Pa)';
            app.G23PaEditField = uieditfield(app.Panel_21, 'numeric');
            app.G23PaEditField.Value = 5047000000;
            app.UDmaterialpropertiesLabel = uilabel(app.MaterialpropertiesTab);
            app.UDmaterialpropertiesLabel.Text = 'UD material properties';
            app.Panel_22 = uipanel(app.MaterialpropertiesTab);
            app.E1PaEditField_2Label = uilabel(app.Panel_22);
            app.E1PaEditField_2Label.Text = 'E1 (Pa)';
            app.E1PaEditField_2 = uieditfield(app.Panel_22, 'numeric');
            app.E1PaEditField_2.Value = 13920000000;
            app.E2PaEditField_2Label = uilabel(app.Panel_22);
            app.E2PaEditField_2Label.Text = 'E2 (Pa)';
            app.E2PaEditField_2 = uieditfield(app.Panel_22, 'numeric');
            app.E2PaEditField_2.Value = 13920000000;
            app.E3PaEditField_2Label = uilabel(app.Panel_22);
            app.E3PaEditField_2Label.Text = 'E3 (Pa)';
            app.E3PaEditField_2 = uieditfield(app.Panel_22, 'numeric');
            app.E3PaEditField_2.Value = 13920000000;
            app.V12EditField_2Label = uilabel(app.Panel_22);
            app.V12EditField_2Label.Text = 'V12';
            app.V12EditField_2 = uieditfield(app.Panel_22, 'numeric');
            app.V12EditField_2.Value = 0.53;
            app.V13EditField_2Label = uilabel(app.Panel_22);
            app.V13EditField_2Label.Text = 'V13';
            app.V13EditField_2 = uieditfield(app.Panel_22, 'numeric');
            app.V13EditField_2.Value = 0.19;
            app.V23EditField_2Label = uilabel(app.Panel_22);
            app.V23EditField_2Label.Text = 'V23';
            app.V23EditField_2 = uieditfield(app.Panel_22, 'numeric');
            app.V23EditField_2.Value = 0.02;
            app.G12PaEditField_2Label = uilabel(app.Panel_22);
            app.G12PaEditField_2Label.Text = 'G12(Pa)';
            app.G12PaEditField_2 = uieditfield(app.Panel_22, 'numeric');
            app.G12PaEditField_2.Value = 11500000000;
            app.G13PaEditField_2Label = uilabel(app.Panel_22);
            app.G13PaEditField_2Label.Text = 'G13(Pa)';
            app.G13PaEditField_2 = uieditfield(app.Panel_22, 'numeric');
            app.G13PaEditField_2.Value = 11500000000;
            app.G23PaEditField_2Label = uilabel(app.Panel_22);
            app.G23PaEditField_2Label.Text = 'G23(Pa)';
            app.G23PaEditField_2 = uieditfield(app.Panel_22, 'numeric');
            app.G23PaEditField_2.Value = 4539000000;
            app.BIAXmaterialpropertiesLabel = uilabel(app.MaterialpropertiesTab);
            app.BIAXmaterialpropertiesLabel.Text = 'BIAX material properties';
            app.Panel_45 = uipanel(app.MaterialpropertiesTab);
            app.E1PaEditField_4Label = uilabel(app.Panel_45);
            app.E1PaEditField_4Label.Text = 'E1 (Pa)';
            app.E1PaEditField_4 = uieditfield(app.Panel_45, 'numeric');
            app.E1PaEditField_4.Value = 39000000000;
            app.E2PaEditField_3Label = uilabel(app.Panel_45);
            app.E2PaEditField_3Label.Text = 'E2 (Pa)';
            app.E2PaEditField_3 = uieditfield(app.Panel_45, 'numeric');
            app.E2PaEditField_3.Value = 13000000000;
            app.E3PaEditField_3Label = uilabel(app.Panel_45);
            app.E3PaEditField_3Label.Text = 'E3 (Pa)';
            app.E3PaEditField_3 = uieditfield(app.Panel_45, 'numeric');
            app.E3PaEditField_3.Value = 13000000000;
            app.V12EditField_4Label = uilabel(app.Panel_45);
            app.V12EditField_4Label.Text = 'V12';
            app.V12EditField_4 = uieditfield(app.Panel_45, 'numeric');
            app.V12EditField_4.Value = 0.205;
            app.V13EditField_3Label = uilabel(app.Panel_45);
            app.V13EditField_3Label.Text = 'V13';
            app.V13EditField_3 = uieditfield(app.Panel_45, 'numeric');
            app.V13EditField_3.Value = 0.205;
            app.V23EditField_3Label = uilabel(app.Panel_45);
            app.V23EditField_3Label.Text = 'V23';
            app.V23EditField_3 = uieditfield(app.Panel_45, 'numeric');
            app.V23EditField_3.Value = 0.349;
            app.G12PaEditField_3Label = uilabel(app.Panel_45);
            app.G12PaEditField_3Label.Text = 'G12(Pa)';
            app.G12PaEditField_3 = uieditfield(app.Panel_45, 'numeric');
            app.G12PaEditField_3.Value = 5400000000;
            app.G13PaEditField_3Label = uilabel(app.Panel_45);
            app.G13PaEditField_3Label.Text = 'G13(Pa)';
            app.G13PaEditField_3 = uieditfield(app.Panel_45, 'numeric');
            app.G13PaEditField_3.Value = 3400000000;
            app.G23PaEditField_3Label = uilabel(app.Panel_45);
            app.G23PaEditField_3Label.Text = 'G23(Pa)';
            app.G23PaEditField_3 = uieditfield(app.Panel_45, 'numeric');
            app.G23PaEditField_3.Value = 3400000000;
            app.UDmaterialpropertiesdifferentmaterialLabel = uilabel(app.MaterialpropertiesTab);
            app.UDmaterialpropertiesdifferentmaterialLabel.Text = 'UD material properties (different material)';
            app.Panel_46 = uipanel(app.MaterialpropertiesTab);
            app.E1PaEditField_5Label = uilabel(app.Panel_46);
            app.E1PaEditField_5Label.Text = 'E1 (Pa)';
            app.E1PaEditField_5 = uieditfield(app.Panel_46, 'numeric');
            app.E1PaEditField_5.Value = 12500000000;
            app.E2PaEditField_4Label = uilabel(app.Panel_46);
            app.E2PaEditField_4Label.Text = 'E2 (Pa)';
            app.E2PaEditField_4 = uieditfield(app.Panel_46, 'numeric');
            app.E2PaEditField_4.Value = 12500000000;
            app.E3PaEditField_4Label = uilabel(app.Panel_46);
            app.E3PaEditField_4Label.Text = 'E3 (Pa)';
            app.E3PaEditField_4 = uieditfield(app.Panel_46, 'numeric');
            app.E3PaEditField_4.Value = 12500000000;
            app.V12EditField_5Label = uilabel(app.Panel_46);
            app.V12EditField_5Label.Text = 'V12';
            app.V12EditField_5 = uieditfield(app.Panel_46, 'numeric');
            app.V12EditField_5.Value = 0.498;
            app.V13EditField_4Label = uilabel(app.Panel_46);
            app.V13EditField_4Label.Text = 'V13';
            app.V13EditField_4 = uieditfield(app.Panel_46, 'numeric');
            app.V13EditField_4.Value = 0.498;
            app.V23EditField_4Label = uilabel(app.Panel_46);
            app.V23EditField_4Label.Text = 'V23';
            app.V23EditField_4 = uieditfield(app.Panel_46, 'numeric');
            app.V23EditField_4.Value = 0.498;
            app.G12PaEditField_4Label = uilabel(app.Panel_46);
            app.G12PaEditField_4Label.Text = 'G12(Pa)';
            app.G12PaEditField_4 = uieditfield(app.Panel_46, 'numeric');
            app.G12PaEditField_4.Value = 10300000000;
            app.G13PaEditField_4Label = uilabel(app.Panel_46);
            app.G13PaEditField_4Label.Text = 'G13(Pa)';
            app.G13PaEditField_4 = uieditfield(app.Panel_46, 'numeric');
            app.G13PaEditField_4.Value = 10300000000;
            app.G23PaEditField_4Label = uilabel(app.Panel_46);
            app.G23PaEditField_4Label.Text = 'G23(Pa)';
            app.G23PaEditField_4 = uieditfield(app.Panel_46, 'numeric');
            app.G23PaEditField_4.Value = 10300000000;
            app.BIAXmaterialpropertiesdifferentmaterialLabel = uilabel(app.MaterialpropertiesTab);
            app.BIAXmaterialpropertiesdifferentmaterialLabel.Text = 'BIAX material properties (different material)';
            app.Panel_23 = uipanel(app.MaterialpropertiesTab);
            app.E1PaEditField_3Label = uilabel(app.Panel_23);
            app.E1PaEditField_3Label.Text = 'E1 (Pa)';
            app.E1PaEditField_3 = uieditfield(app.Panel_23, 'numeric');
            app.E1PaEditField_3.Value = 3241000000;
            app.V12EditField_3Label = uilabel(app.Panel_23);
            app.V12EditField_3Label.Text = 'V12';
            app.V12EditField_3 = uieditfield(app.Panel_23, 'numeric');
            app.V12EditField_3.Value = 0.38;
            app.ViscosityPasLabel = uilabel(app.Panel_23);
            app.ViscosityPasLabel.Text = 'Viscosity (Pa*s)';
            app.ViscosityPasEditField = uieditfield(app.Panel_23, 'numeric');
            app.ViscosityPasEditField.Value = 1e-05;
            app.ResinmaterialpropertiesLabel = uilabel(app.MaterialpropertiesTab);
            app.ResinmaterialpropertiesLabel.Text = 'Resin material properties';
            app.CohesivematerialpropertiesGeneralStaticAnalysisStepcontrolTab = uitab(app.TabGroup);
            app.CohesivematerialpropertiesGeneralStaticAnalysisStepcontrolTab.Title = 'Cohesive material properties & General Static Analysis & Step control';
            app.Panel_28 = uipanel(app.CohesivematerialpropertiesGeneralStaticAnalysisStepcontrolTab);
            app.EnnPamEditFieldLabel = uilabel(app.Panel_28);
            app.EnnPamEditFieldLabel.Text = 'Enn (Pa/m)';
            app.EnnPamEditField = uieditfield(app.Panel_28, 'numeric');
            app.EnnPamEditField.Tooltip = {'E'};
            app.EnnPamEditField.Value = 300000000000000;
            app.EssPamEditFieldLabel = uilabel(app.Panel_28);
            app.EssPamEditFieldLabel.Text = 'Ess (Pa/m)';
            app.EssPamEditField = uieditfield(app.Panel_28, 'numeric');
            app.EssPamEditField.Tooltip = {'G1'};
            app.EssPamEditField.Value = 115000000000000;
            app.EttPamEditFieldLabel = uilabel(app.Panel_28);
            app.EttPamEditFieldLabel.Text = 'Ett (Pa/m)';
            app.EttPamEditField = uieditfield(app.Panel_28, 'numeric');
            app.EttPamEditField.Tooltip = {'G2'};
            app.EttPamEditField.Value = 115000000000000;
            app.LinearelastictractionseparationbehaviorLabel = uilabel(app.CohesivematerialpropertiesGeneralStaticAnalysisStepcontrolTab);
            app.LinearelastictractionseparationbehaviorLabel.Text = 'Linear elastic traction-separation behavior';
            app.Panel_31 = uipanel(app.CohesivematerialpropertiesGeneralStaticAnalysisStepcontrolTab);
            app.Resin1EditFieldLabel = uilabel(app.Panel_31);
            app.Resin1EditFieldLabel.Text = 'Resin 1';
            app.Resin1EditField = uieditfield(app.Panel_31, 'numeric');
            app.Resin1EditField.Value = 200;
            app.Resin2EditFieldLabel = uilabel(app.Panel_31);
            app.Resin2EditFieldLabel.Text = 'Resin 2';
            app.Resin2EditField = uieditfield(app.Panel_31, 'numeric');
            app.Resin2EditField.Value = 1000;
            app.Resin3EditFieldLabel = uilabel(app.Panel_31);
            app.Resin3EditFieldLabel.Text = 'Resin 3';
            app.Resin3EditField = uieditfield(app.Panel_31, 'numeric');
            app.Resin3EditField.Value = 1000;
            app.UD1EditFieldLabel = uilabel(app.Panel_31);
            app.UD1EditFieldLabel.Text = 'UD 1';
            app.UD1EditField = uieditfield(app.Panel_31, 'numeric');
            app.UD1EditField.Value = 100;
            app.UD2EditFieldLabel = uilabel(app.Panel_31);
            app.UD2EditFieldLabel.Text = 'UD 2';
            app.UD2EditField = uieditfield(app.Panel_31, 'numeric');
            app.UD2EditField.Value = 100;
            app.UD3EditFieldLabel = uilabel(app.Panel_31);
            app.UD3EditFieldLabel.Text = 'UD 3';
            app.UD3EditField = uieditfield(app.Panel_31, 'numeric');
            app.UD3EditField.Value = 100;
            app.BIAX1EditFieldLabel = uilabel(app.Panel_31);
            app.BIAX1EditFieldLabel.Text = 'BIAX 1';
            app.BIAX1EditField = uieditfield(app.Panel_31, 'numeric');
            app.BIAX1EditField.Value = 100;
            app.BIAX2EditFieldLabel = uilabel(app.Panel_31);
            app.BIAX2EditFieldLabel.Text = 'BIAX 2';
            app.BIAX2EditField = uieditfield(app.Panel_31, 'numeric');
            app.BIAX2EditField.Value = 100;
            app.BIAX3EditFieldLabel = uilabel(app.Panel_31);
            app.BIAX3EditFieldLabel.Text = 'BIAX 3';
            app.BIAX3EditField = uieditfield(app.Panel_31, 'numeric');
            app.BIAX3EditField.Value = 100;
            app.UD1DIFFEditFieldLabel = uilabel(app.Panel_31);
            app.UD1DIFFEditFieldLabel.Text = 'UD 1 DIFF';
            app.UD1DIFFEditField = uieditfield(app.Panel_31, 'numeric');
            app.UD1DIFFEditField.Value = 100;
            app.UD2DIFFEditFieldLabel = uilabel(app.Panel_31);
            app.UD2DIFFEditFieldLabel.Text = 'UD 2 DIFF';
            app.UD2DIFFEditField = uieditfield(app.Panel_31, 'numeric');
            app.UD2DIFFEditField.Value = 100;
            app.UD3DIFFEditFieldLabel = uilabel(app.Panel_31);
            app.UD3DIFFEditFieldLabel.Text = 'UD 3 DIFF';
            app.UD3DIFFEditField = uieditfield(app.Panel_31, 'numeric');
            app.UD3DIFFEditField.Value = 100;
            app.BIAX1DIFFEditFieldLabel = uilabel(app.Panel_31);
            app.BIAX1DIFFEditFieldLabel.Text = 'BIAX 1 DIFF';
            app.BIAX1DIFFEditField = uieditfield(app.Panel_31, 'numeric');
            app.BIAX1DIFFEditField.Value = 100;
            app.BIAX2DIFFEditFieldLabel = uilabel(app.Panel_31);
            app.BIAX2DIFFEditFieldLabel.Text = 'BIAX 2 DIFF';
            app.BIAX2DIFFEditField = uieditfield(app.Panel_31, 'numeric');
            app.BIAX2DIFFEditField.Value = 100;
            app.BIAX3DIFFEditFieldLabel = uilabel(app.Panel_31);
            app.BIAX3DIFFEditFieldLabel.Text = 'BIAX 3 DIFF';
            app.BIAX3DIFFEditField = uieditfield(app.Panel_31, 'numeric');
            app.BIAX3DIFFEditField.Value = 100;
            app.DamageevolutionFractureenergyJm2Label = uilabel(app.CohesivematerialpropertiesGeneralStaticAnalysisStepcontrolTab);
            app.DamageevolutionFractureenergyJm2Label.Text = 'Damage evolution: Fracture energy. (J/m^2)';
            app.Panel_47 = uipanel(app.CohesivematerialpropertiesGeneralStaticAnalysisStepcontrolTab);
            app.ststepsEditFieldLabel = uilabel(app.Panel_47);
            app.ststepsEditFieldLabel.Text = '1st step (s)';
            app.ststepsEditField = uieditfield(app.Panel_47, 'numeric');
            app.ststepsEditField.Value = 0.1;
            app.TimeperiodsEditFieldLabel = uilabel(app.Panel_47);
            app.TimeperiodsEditFieldLabel.Text = 'Time period (s)';
            app.TimeperiodsEditField = uieditfield(app.Panel_47, 'numeric');
            app.TimeperiodsEditField.Value = 1;
            app.MinimumtimeincrementsEditFieldLabel = uilabel(app.Panel_47);
            app.MinimumtimeincrementsEditFieldLabel.Text = 'Minimum time increment (s)';
            app.MinimumtimeincrementsEditField = uieditfield(app.Panel_47, 'numeric');
            app.MinimumtimeincrementsEditField.Value = 1e-25;
            app.MaximumtimeincrementsEditFieldLabel = uilabel(app.Panel_47);
            app.MaximumtimeincrementsEditFieldLabel.Text = 'Maximum time increment (s)';
            app.MaximumtimeincrementsEditField = uieditfield(app.Panel_47, 'numeric');
            app.MaximumtimeincrementsEditField.Value = 0.1;
            app.GeneralStaticAnalysisLabel = uilabel(app.CohesivematerialpropertiesGeneralStaticAnalysisStepcontrolTab);
            app.GeneralStaticAnalysisLabel.Text = 'General Static Analysis';
            app.Panel_32 = uipanel(app.CohesivematerialpropertiesGeneralStaticAnalysisStepcontrolTab);
            app.UDEditField_2Label = uilabel(app.Panel_32);
            app.UDEditField_2Label.Text = 'UD';
            app.UDEditField_2 = uieditfield(app.Panel_32, 'numeric');
            app.UDEditField_2.Value = 2;
            app.BIAXEditField_2Label = uilabel(app.Panel_32);
            app.BIAXEditField_2Label.Text = 'BIAX';
            app.BIAXEditField_2 = uieditfield(app.Panel_32, 'numeric');
            app.BIAXEditField_2.Value = 2;
            app.ResinEditFieldLabel = uilabel(app.Panel_32);
            app.ResinEditFieldLabel.Text = 'Resin';
            app.ResinEditField = uieditfield(app.Panel_32, 'numeric');
            app.ResinEditField.Value = 3.8;
            app.UD_DIFFEditField_2Label = uilabel(app.Panel_32);
            app.UD_DIFFEditField_2Label.Text = 'UD_DIFF';
            app.UD_DIFFEditField_2 = uieditfield(app.Panel_32, 'numeric');
            app.UD_DIFFEditField_2.Value = 2;
            app.BIAX_DIFFEditField_2Label = uilabel(app.Panel_32);
            app.BIAX_DIFFEditField_2Label.Text = 'BIAX_DIFF';
            app.BIAX_DIFFEditField_2 = uieditfield(app.Panel_32, 'numeric');
            app.BIAX_DIFFEditField_2.Value = 2;
            app.DamageevolutionBenzeggaghKenaneLabel = uilabel(app.CohesivematerialpropertiesGeneralStaticAnalysisStepcontrolTab);
            app.DamageevolutionBenzeggaghKenaneLabel.Text = 'Damage evolution: Benzeggagh-Kenane';
            app.Panel_29 = uipanel(app.CohesivematerialpropertiesGeneralStaticAnalysisStepcontrolTab);
            app.UDEditFieldLabel = uilabel(app.Panel_29);
            app.UDEditFieldLabel.Text = 'UD';
            app.UDEditField = uieditfield(app.Panel_29, 'numeric');
            app.UDEditField.Tooltip = {'Maximum nominal stress. Normal-only mode. '};
            app.UDEditField.Value = 1000000000;
            app.BIAXEditFieldLabel = uilabel(app.Panel_29);
            app.BIAXEditFieldLabel.Text = 'BIAX';
            app.BIAXEditField = uieditfield(app.Panel_29, 'numeric');
            app.BIAXEditField.Tooltip = {'Maximum nominal stress. Normal-only mode. '};
            app.BIAXEditField.Value = 1000000000;
            app.Resin1EditField_2Label = uilabel(app.Panel_29);
            app.Resin1EditField_2Label.Text = 'Resin 1';
            app.Resin1EditField_2 = uieditfield(app.Panel_29, 'numeric');
            app.Resin1EditField_2.Tooltip = {'Maximum nominal stress. Normal-only mode. '};
            app.Resin1EditField_2.Value = 7500000;
            app.Resin2EditField_2Label = uilabel(app.Panel_29);
            app.Resin2EditField_2Label.Text = 'Resin 2';
            app.Resin2EditField_2 = uieditfield(app.Panel_29, 'numeric');
            app.Resin2EditField_2.Tooltip = {'Nominal stress. First Direction'};
            app.Resin2EditField_2.Value = 15000000;
            app.Resin3EditField_2Label = uilabel(app.Panel_29);
            app.Resin3EditField_2Label.Text = 'Resin 3';
            app.Resin3EditField_2 = uieditfield(app.Panel_29, 'numeric');
            app.Resin3EditField_2.Tooltip = {'Nominal stress. Second direction'};
            app.Resin3EditField_2.Value = 15000000;
            app.UD_DIFFEditFieldLabel = uilabel(app.Panel_29);
            app.UD_DIFFEditFieldLabel.Text = 'UD_DIFF';
            app.UD_DIFFEditField = uieditfield(app.Panel_29, 'numeric');
            app.UD_DIFFEditField.Tooltip = {'Maximum nominal stress. Normal-only mode. '};
            app.UD_DIFFEditField.Value = 1000000000;
            app.BIAX_DIFFEditFieldLabel = uilabel(app.Panel_29);
            app.BIAX_DIFFEditFieldLabel.Text = 'BIAX_DIFF';
            app.BIAX_DIFFEditField = uieditfield(app.Panel_29, 'numeric');
            app.BIAX_DIFFEditField.Tooltip = {'Maximum nominal stress. Normal-only mode. '};
            app.BIAX_DIFFEditField.Value = 1000000000;
            app.DamageinitiationelasticlimitPaCriterionMaxstressQUADSLabel = uilabel(app.CohesivematerialpropertiesGeneralStaticAnalysisStepcontrolTab);
            app.DamageinitiationelasticlimitPaCriterionMaxstressQUADSLabel.Text = 'Damage initiation elastic limit (Pa). Criterion: Max stress & QUADS ';
            app.Panel_48 = uipanel(app.CohesivematerialpropertiesGeneralStaticAnalysisStepcontrolTab);
            app.StabilizationforcohesiveEditFieldLabel = uilabel(app.Panel_48);
            app.StabilizationforcohesiveEditFieldLabel.Text = 'Stabilization for cohesive';
            app.StabilizationforcohesiveEditField = uieditfield(app.Panel_48, 'numeric');
            app.StabilizationforcohesiveEditField.Value = 0.01;
            app.DamagestabilizationLabel = uilabel(app.CohesivematerialpropertiesGeneralStaticAnalysisStepcontrolTab);
            app.DamagestabilizationLabel.Text = 'Damage stabilization';
            app.Panel_49 = uipanel(app.CohesivematerialpropertiesGeneralStaticAnalysisStepcontrolTab);
            app.IoEditFieldLabel = uilabel(app.Panel_49);
            app.IoEditFieldLabel.Text = 'Io';
            app.IoEditField = uieditfield(app.Panel_49, 'numeric');
            app.IoEditField.Tooltip = {'Iteration for a residual check'};
            app.IoEditField.Value = 16;
            app.IREditFieldLabel = uilabel(app.Panel_49);
            app.IREditFieldLabel.Text = 'IR';
            app.IREditField = uieditfield(app.Panel_49, 'numeric');
            app.IREditField.Tooltip = {'Equilibrium iteration for a logarithmic rate of convergence check'};
            app.IREditField.Value = 32;
            app.StepcontrolequilibriumTimeincrementationLabel = uilabel(app.CohesivematerialpropertiesGeneralStaticAnalysisStepcontrolTab);
            app.StepcontrolequilibriumTimeincrementationLabel.Text = 'Step control equilibrium: Time incrementation  ';
            app.RunOutputTab = uitab(app.TabGroup);
            app.RunOutputTab.Title = 'Run/Output';
            app.UIAxes = uiaxes(app.RunOutputTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel_17 = uipanel(app.RunOutputTab);
            app.RunButton = uibutton(app.Panel_17, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.JOBEditFieldLabel = uilabel(app.Panel_17);
            app.JOBEditFieldLabel.Text = 'JOB';
            app.JOBEditField = uieditfield(app.Panel_17, 'text');
            app.JOBEditField.ValueChangingFcn = createCallbackFcn(app, @ChooseplotDropDownValueChanged, true);
            app.TimesEditFieldLabel = uilabel(app.Panel_17);
            app.TimesEditFieldLabel.Text = 'Time (s)';
            app.TimesEditField = uieditfield(app.Panel_17, 'numeric');
            app.RestartButton = uibutton(app.Panel_17, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.EGpaEditFieldLabel = uilabel(app.Panel_17);
            app.EGpaEditFieldLabel.Text = 'E (Gpa)';
            app.EGpaEditField = uieditfield(app.Panel_17, 'numeric');
            app.DNmEditFieldLabel = uilabel(app.Panel_17);
            app.DNmEditFieldLabel.Text = 'D (Nm²)';
            app.DNmEditField = uieditfield(app.Panel_17, 'numeric');
            app.ChooseplotDropDownLabel = uilabel(app.RunOutputTab);
            app.ChooseplotDropDownLabel.Text = 'Choose plot';
            app.ChooseplotDropDown = uidropdown(app.RunOutputTab);
            app.ChooseplotDropDown.Items = {'1. Wrinkle interpolation curve', '2. Wrinkled unhealthy layers', '3. Laminate with defect', '4. Detect defect', '5. Remove defect', '6. Replace damaged layers', '7. Element grid size', '8. Load vs Displacement'};
            app.ChooseplotDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseplotDropDownValueChanged, true);
            app.ChooseplotDropDown.Value = '1. Wrinkle interpolation curve';
            app.TotalthicknessmmEditFieldLabel = uilabel(app.RunOutputTab);
            app.TotalthicknessmmEditFieldLabel.Text = 'Total thickness (mm)';
            app.TotalthicknessmmEditField = uieditfield(app.RunOutputTab, 'numeric');
            app.TotallengthofthecompositemmLabel = uilabel(app.RunOutputTab);
            app.TotallengthofthecompositemmLabel.Text = 'Total length of the composite (mm)';
            app.TotallengthofthecompositemmEditField = uieditfield(app.RunOutputTab, 'numeric');
            app.TotallengthoftherepairmmLabel = uilabel(app.RunOutputTab);
            app.TotallengthoftherepairmmLabel.Text = 'Total length of the repair (mm)';
            app.TotallengthoftherepairmmEditField = uieditfield(app.RunOutputTab, 'numeric');
            app.MENULabel = uilabel(app.RunOutputTab);
            app.MENULabel.Text = 'MENU';
            app.OutputStressStrainTab = uitab(app.TabGroup);
            app.OutputStressStrainTab.Title = 'Output: Stress & Strain';
            app.MaximumStressLabel = uilabel(app.OutputStressStrainTab);
            app.MaximumStressLabel.Text = 'Maximum Stress';
            app.Panel_18 = uipanel(app.OutputStressStrainTab);
            app.S11MPaLabel = uilabel(app.Panel_18);
            app.S11MPaLabel.Text = 'S11  (MPa)';
            app.S11MPaEditField = uieditfield(app.Panel_18, 'numeric');
            app.S22MPaEditFieldLabel = uilabel(app.Panel_18);
            app.S22MPaEditFieldLabel.Text = 'S22  (MPa)';
            app.S22MPaEditField = uieditfield(app.Panel_18, 'numeric');
            app.S33MPaEditFieldLabel = uilabel(app.Panel_18);
            app.S33MPaEditFieldLabel.Text = 'S33  (MPa)';
            app.S33MPaEditField = uieditfield(app.Panel_18, 'numeric');
            app.S12MPaEditFieldLabel = uilabel(app.Panel_18);
            app.S12MPaEditFieldLabel.Text = 'S12  (MPa)';
            app.S12MPaEditField = uieditfield(app.Panel_18, 'numeric');
            app.MaximumStrainLabel = uilabel(app.OutputStressStrainTab);
            app.MaximumStrainLabel.Text = ' Maximum Strain';
            app.Panel_19 = uipanel(app.OutputStressStrainTab);
            app.LE11EditFieldLabel = uilabel(app.Panel_19);
            app.LE11EditFieldLabel.Text = 'LE11';
            app.LE11EditField = uieditfield(app.Panel_19, 'numeric');
            app.LE22EditFieldLabel = uilabel(app.Panel_19);
            app.LE22EditFieldLabel.Text = 'LE22';
            app.LE22EditField = uieditfield(app.Panel_19, 'numeric');
            app.LE33EditFieldLabel = uilabel(app.Panel_19);
            app.LE33EditFieldLabel.Text = 'LE33';
            app.LE33EditField = uieditfield(app.Panel_19, 'numeric');
            app.LE12EditFieldLabel = uilabel(app.Panel_19);
            app.LE12EditFieldLabel.Text = 'LE12';
            app.LE12EditField = uieditfield(app.Panel_19, 'numeric');
            app.ChooseelementtypeDropDownLabel = uilabel(app.OutputStressStrainTab);
            app.ChooseelementtypeDropDownLabel.Text = 'Choose element type';
            app.ChooseelementtypeDropDown = uidropdown(app.OutputStressStrainTab);
            app.ChooseelementtypeDropDown.Items = {'1. EINTERFACE', '2. EOVERLAM', '3. EPARENTBIAX', '4. EPARENTUD', '5. EREPAIRBIAX1', '6. EREPAIRUD1', '7. ERESIN', '8. RESINRICH', '9. EREPAIRBIAX', '10. EREPAIRUD', '11. ECOMPOSITEBIAX', '12. ECOMPOSITEUD'};
            app.ChooseelementtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseelementtypeDropDownValueChanged, true);
            app.ChooseelementtypeDropDown.Value = '1. EINTERFACE';
            app.ReactionForcesLabel = uilabel(app.OutputStressStrainTab);
            app.ReactionForcesLabel.Text = 'Reaction Forces';
            app.Panel_20 = uipanel(app.OutputStressStrainTab);
            app.RF1NEditFieldLabel = uilabel(app.Panel_20);
            app.RF1NEditFieldLabel.Text = 'RF1 (N)';
            app.RF1NEditField = uieditfield(app.Panel_20, 'numeric');
            app.RF2NEditFieldLabel = uilabel(app.Panel_20);
            app.RF2NEditFieldLabel.Text = 'RF2 (N)';
            app.RF2NEditField = uieditfield(app.Panel_20, 'numeric');
            app.RM3NmEditFieldLabel = uilabel(app.Panel_20);
            app.RM3NmEditFieldLabel.Text = 'RM3 (Nm)';
            app.RM3NmEditField = uieditfield(app.Panel_20, 'numeric');
            app.JOBEditField_2Label = uilabel(app.OutputStressStrainTab);
            app.JOBEditField_2Label.Text = 'JOB';
            app.JOBEditField_2 = uieditfield(app.OutputStressStrainTab, 'text');
            app.Guide1Tab = uitab(app.TabGroup);
            app.Guide1Tab.Title = 'Guide 1';
            app.InputFilenameTextAreaLabel = uilabel(app.Guide1Tab);
            app.InputFilenameTextAreaLabel.Text = 'Input File name';
            app.InputFilenameTextArea = uitextarea(app.Guide1Tab);
            app.InputFilenameTextArea.Value = {'In this box, the input of the wrinkle file name is needed. The input also needs to know the sheet of that excel file.'; 'See example: CASE 1.xlsx'};
            app.OutputfilenameTextAreaLabel = uilabel(app.Guide1Tab);
            app.OutputfilenameTextAreaLabel.Text = 'Output filename';
            app.OutputfilenameTextArea = uitextarea(app.Guide1Tab);
            app.OutputfilenameTextArea.Value = {'In this box, the output file name is given. This will be the name of the .inp file that ABAQUS uses as input and the name of all the files that ABAQUS will give as output (.odb, .dat, .mdl, .msg, .prt, .res, .sim, .sta and .stt)'};
            app.OverlappinglayerlengthTextAreaLabel = uilabel(app.Guide1Tab);
            app.OverlappinglayerlengthTextAreaLabel.Text = 'Overlapping layer length';
            app.OverlappinglayerlengthTextArea = uitextarea(app.Guide1Tab);
            app.OverlappinglayerlengthTextArea.Value = {'It''s the extra length of the new plys that overlap with the old layers below the repaired layer. It''s the part where the newly repaired plys contact the old plys.'; 'See image: '};
            app.DefinematerialthicknessTextAreaLabel = uilabel(app.Guide1Tab);
            app.DefinematerialthicknessTextAreaLabel.Text = 'Define material thickness';
            app.DefinematerialthicknessTextArea = uitextarea(app.Guide1Tab);
            app.DefinematerialthicknessTextArea.Value = {'Each of the elements in this box represents the thickness of the different layers. In the box ''thickness of the overlamina'', the input should be: ud or biax. '};
            app.LayupSelectionProcessTextAreaLabel = uilabel(app.Guide1Tab);
            app.LayupSelectionProcessTextAreaLabel.Text = 'Layup Selection Process';
            app.LayupSelectionProcessTextArea = uitextarea(app.Guide1Tab);
            app.LayupSelectionProcessTextArea.Value = {'The layup defect ply order is written from top to bottom for both with and without defect.'; ''; 'In this box, the type of process that the MPSS will carry out is chosen in ''Flag''. In ''Parent material'' parent laminate material can be changed if the materials between the repair laminate and the parent laminate differ.'};
            app.LoadingTextAreaLabel = uilabel(app.Guide1Tab);
            app.LoadingTextAreaLabel.Text = 'Loading';
            app.LoadingTextArea = uitextarea(app.Guide1Tab);
            app.LoadingTextArea.Value = {'In this box, the type of loading (Tension or Bending) and their values should be given.'; ''; 'The displacement X is along the UD fiber direction (mm). The rotation angle UR3 represents the rotation of the pinpoints for a simply supported beam (rad).'};
            app.Image = uiimage(app.Guide1Tab);
            app.Image.ImageSource = 'Overlapping length example.jpg';
            app.Guide2Tab = uitab(app.TabGroup);
            app.Guide2Tab.Title = 'Guide 2';
            app.DiscretasingdensityTextAreaLabel = uilabel(app.Guide2Tab);
            app.DiscretasingdensityTextAreaLabel.Text = 'Discretasing density';
            app.DiscretasingdensityTextArea = uitextarea(app.Guide2Tab);
            app.DiscretasingdensityTextArea.Value = {'It represents the number of elements into which the part will be divided. It depends on the element size. This number should be bigger than the sum of the width vector elements to work. eg. 1000'};
            app.WidthofdefectTextAreaLabel = uilabel(app.Guide2Tab);
            app.WidthofdefectTextAreaLabel.Text = 'Width of defect';
            app.WidthofdefectTextArea = uitextarea(app.Guide2Tab);
            app.WidthofdefectTextArea.Value = {'It represents the width of the defect in the X direction.'};
            app.OverlaminaAdditionontopofrepairTextAreaLabel = uilabel(app.Guide2Tab);
            app.OverlaminaAdditionontopofrepairTextAreaLabel.Text = 'Overlamina Addition on top of repair';
            app.OverlaminaAdditionontopofrepairTextArea = uitextarea(app.Guide2Tab);
            app.OverlaminaAdditionontopofrepairTextArea.Value = {'In this box, the user can choose to have or not an overlamina on top of the repair and if that overlamina has overlapping'};
            app.CurvepanelTextAreaLabel = uilabel(app.Guide2Tab);
            app.CurvepanelTextAreaLabel.Text = 'Curve panel';
            app.CurvepanelTextArea = uitextarea(app.Guide2Tab);
            app.CurvepanelTextArea.Value = {'Allows the part to be curved. The parameter makes the parabola wider (lower a_curve) or narrower (higher a_curve)  using the inverse parabolic function: '; ''; 'e.g. 0.5'};
            app.ResinElementTextAreaLabel = uilabel(app.Guide2Tab);
            app.ResinElementTextAreaLabel.Text = 'Resin Element';
            app.ResinElementTextArea = uitextarea(app.Guide2Tab);
            app.ResinElementTextArea.Value = {'In this box, the user can choose between having a solid homogeneous or cohesive element for the resin layers.'};
            app.Geometryof2DmodelTextAreaLabel = uilabel(app.Guide2Tab);
            app.Geometryof2DmodelTextAreaLabel.Text = 'Geometry of 2D model';
            app.Geometryof2DmodelTextArea = uitextarea(app.Guide2Tab);
            app.Geometryof2DmodelTextArea.Value = {'In this box, the user can select the dimension of the 2D sample.'; 'Width from middle of defect [mm].'; 'The additional allowance at the edges [mm].'; 'Both are in the X direction.'};
            app.ElementnumberTextAreaLabel = uilabel(app.Guide2Tab);
            app.ElementnumberTextAreaLabel.Text = 'Element number';
            app.ElementnumberTextArea = uitextarea(app.Guide2Tab);
            app.ElementnumberTextArea.Value = {'It represents the discretization distribution of the part. It represents the coarse to fine distribution. It should be higher where there is a change between the old ply and the new repair ply so that ABAQUS correctly simulates the part.'};
            app.GaussianparametersguessTextAreaLabel = uilabel(app.Guide2Tab);
            app.GaussianparametersguessTextAreaLabel.Text = 'Gaussian parameters guess';
            app.GaussianparametersguessTextArea = uitextarea(app.Guide2Tab);
            app.GaussianparametersguessTextArea.Value = {'They are used for the interpolation curve needed to make the wrinkle representation. It is important that the centre is around the centre of the defect.'};
            app.Image2 = uiimage(app.Guide2Tab);
            app.Image2.ImageSource = 'Captura de pantalla 2022-10-05 160345.jpg';
            app.Image3 = uiimage(app.Guide2Tab);
            app.Image3.ImageSource = 'Captura de pantalla 2022-10-05 160714.jpg';
            app.PlybyplymatchTextArea_2Label = uilabel(app.Guide2Tab);
            app.PlybyplymatchTextArea_2Label.Text = 'Ply by ply match';
            app.PlybyplymatchTextArea_2 = uitextarea(app.Guide2Tab);
            app.PlybyplymatchTextArea_2.Value = {'In this box, the user can choose between having a mismatch between the newly repaired plys and the old plys. The mismatch is done horizontally so BIAX doesn''t touch BIAX and UD doesn''t touch UD.  It is an axiom of this type of repair that the material stiffness of the overlapping repair ply should match that of the underlying parent ply in order that load should transfer successfully through '; 'the stack of plies. So it is advisable to have the mismatch always ON.'; 'See image:'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MPSS_GUI4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end